classdef roskam < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup3                     matlab.ui.container.TabGroup
        ResumenTab                    matlab.ui.container.Tab
        tipo                          matlab.ui.control.DropDown
        TipodeaeronaveDropDownLabel   matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        PesoenvacoTab                 matlab.ui.container.Tab
        TabGroup5                     matlab.ui.container.TabGroup
        FraccindecombustibleTab       matlab.ui.container.Tab
        TabGroup2                     matlab.ui.container.TabGroup
        AscensoTab                    matlab.ui.container.Tab
        Tabla22Button1                matlab.ui.control.Button
        n_p_cl                        matlab.ui.control.EditField
        W4Label_7                     matlab.ui.control.Label
        c_cl                          matlab.ui.control.EditField
        W4Label_5                     matlab.ui.control.Label
        W4Label_4                     matlab.ui.control.Label
        V_cl                          matlab.ui.control.EditField
        LD_cl                         matlab.ui.control.EditField
        W4Label_3                     matlab.ui.control.Label
        E_cl                          matlab.ui.control.EditField
        W4Label_2                     matlab.ui.control.Label
        CruceroTab                    matlab.ui.container.Tab
        Tabla22Button2                matlab.ui.control.Button
        V_cr                          matlab.ui.control.EditField
        W4Label_12                    matlab.ui.control.Label
        LD_cr                         matlab.ui.control.EditField
        W4Label_11                    matlab.ui.control.Label
        n_p_cr                        matlab.ui.control.EditField
        W4Label_10                    matlab.ui.control.Label
        c_cr                          matlab.ui.control.EditField
        W4Label_9                     matlab.ui.control.Label
        R_cr                          matlab.ui.control.EditField
        W4Label_8                     matlab.ui.control.Label
        LoiterTab                     matlab.ui.container.Tab
        Tabla22Button3                matlab.ui.control.Button
        E_lo                          matlab.ui.control.EditField
        W4Label_17                    matlab.ui.control.Label
        V_lo                          matlab.ui.control.EditField
        W4Label_16                    matlab.ui.control.Label
        n_p_lo                        matlab.ui.control.EditField
        W4Label_15                    matlab.ui.control.Label
        c_lo                          matlab.ui.control.EditField
        W4Label_14                    matlab.ui.control.Label
        LD_lo                         matlab.ui.control.EditField
        W4Label_13                    matlab.ui.control.Label
        W98                           matlab.ui.control.EditField
        W8Label_2                     matlab.ui.control.Label
        m_ff                          matlab.ui.control.EditField
        M_ffEditFieldLabel            matlab.ui.control.Label
        W87                           matlab.ui.control.EditField
        W8Label                       matlab.ui.control.Label
        W76                           matlab.ui.control.EditField
        W7Label                       matlab.ui.control.Label
        W65                           matlab.ui.control.EditField
        W6Label                       matlab.ui.control.Label
        W54                           matlab.ui.control.EditField
        W5Label                       matlab.ui.control.Label
        W43                           matlab.ui.control.EditField
        W4Label                       matlab.ui.control.Label
        W32                           matlab.ui.control.EditField
        W3Label                       matlab.ui.control.Label
        W21                           matlab.ui.control.EditField
        W2Label                       matlab.ui.control.Label
        W1TO                          matlab.ui.control.EditField
        W_1Label                      matlab.ui.control.Label
        Tabla21Button                 matlab.ui.control.Button
        PesosTab                      matlab.ui.container.Tab
        WEtarget                      matlab.ui.control.EditField
        W5Label_13                    matlab.ui.control.Label
        WError                        matlab.ui.control.EditField
        W5Label_12                    matlab.ui.control.Label
        W_to                          matlab.ui.control.EditField
        W5Label_11                    matlab.ui.control.Label
        WE                            matlab.ui.control.EditField
        W5Label_10                    matlab.ui.control.Label
        Wcrew                         matlab.ui.control.EditField
        W5Label_9                     matlab.ui.control.Label
        Wpl                           matlab.ui.control.EditField
        W5Label_8                     matlab.ui.control.Label
        Wtfo                          matlab.ui.control.EditField
        W5Label_7                     matlab.ui.control.Label
        Wf_res                        matlab.ui.control.EditField
        W5Label_6                     matlab.ui.control.Label
        Wf_used                       matlab.ui.control.EditField
        W5Label_5                     matlab.ui.control.Label
        Wf                            matlab.ui.control.EditField
        W5Label_4                     matlab.ui.control.Label
        eqerror                       matlab.ui.control.Label
        WPlotButton                   matlab.ui.control.Button
        we_eq                         matlab.ui.control.Label
        PuntodediseoTab               matlab.ui.container.Tab
        TabGroup4                     matlab.ui.container.TabGroup
        StallTab                      matlab.ui.container.Tab
        stallvs                       matlab.ui.control.EditField
        W5Label_22                    matlab.ui.control.Label
        stallcl                       matlab.ui.control.EditField
        W5Label_21                    matlab.ui.control.Label
        stallrho                      matlab.ui.control.EditField
        W5Label_20                    matlab.ui.control.Label
        stallheight                   matlab.ui.control.EditField
        W5Label_19                    matlab.ui.control.Label
        stallplotenable               matlab.ui.control.CheckBox
        stallplot                     matlab.ui.control.CheckBox
        stallminus                    matlab.ui.control.Button
        stallplus                     matlab.ui.control.Button
        stallPlotButton               matlab.ui.control.Button
        stalltable                    matlab.ui.control.Table
        Tabla31Button1                matlab.ui.control.Button
        TakeOffTab                    matlab.ui.container.Tab
        toStofl                       matlab.ui.control.EditField
        W5Label_18                    matlab.ui.control.Label
        toclmax                       matlab.ui.control.EditField
        W5Label_17                    matlab.ui.control.Label
        tosigma                       matlab.ui.control.EditField
        W5Label_16                    matlab.ui.control.Label
        totop25                       matlab.ui.control.EditField
        W5Label_15                    matlab.ui.control.Label
        toheight                      matlab.ui.control.EditField
        W5Label_14                    matlab.ui.control.Label
        toplotenable                  matlab.ui.control.CheckBox
        toplot                        matlab.ui.control.CheckBox
        tominus                       matlab.ui.control.Button
        toplus                        matlab.ui.control.Button
        toPlotButton                  matlab.ui.control.Button
        Tabla31Button2                matlab.ui.control.Button
        totable                       matlab.ui.control.Table
        LandTab                       matlab.ui.container.Tab
        lanrel                        matlab.ui.control.EditField
        W5Label_28                    matlab.ui.control.Label
        lansl                         matlab.ui.control.EditField
        W5Label_27                    matlab.ui.control.Label
        lancl                         matlab.ui.control.EditField
        W5Label_26                    matlab.ui.control.Label
        lanrho                        matlab.ui.control.EditField
        W5Label_25                    matlab.ui.control.Label
        lanheight                     matlab.ui.control.EditField
        W5Label_23                    matlab.ui.control.Label
        lanplotenable                 matlab.ui.control.CheckBox
        lanminus                      matlab.ui.control.Button
        lanplus                       matlab.ui.control.Button
        lanPlotButton                 matlab.ui.control.Button
        lantable                      matlab.ui.control.Table
        Tabla31Button3                matlab.ui.control.Button
        lanplot                       matlab.ui.control.CheckBox
        DragTab                       matlab.ui.container.Tab
        dragtab                       matlab.ui.container.TabGroup
        dragtab1                      matlab.ui.container.Tab
        dragws                        matlab.ui.control.EditField
        W5Label_41                    matlab.ui.control.Label
        Tabla36Button1                matlab.ui.control.Button
        drage                         matlab.ui.control.EditField
        W5Label_40                    matlab.ui.control.Label
        dragrightarrowLabel           matlab.ui.control.Label
        draga                         matlab.ui.control.EditField
        W5Label_39                    matlab.ui.control.Label
        dragcd0                       matlab.ui.control.EditField
        W5Label_38                    matlab.ui.control.Label
        drags                         matlab.ui.control.EditField
        W5Label_37                    matlab.ui.control.Label
        dragf                         matlab.ui.control.EditField
        W5Label_36                    matlab.ui.control.Label
        dragcf                        matlab.ui.control.DropDown
        c_fDropDownLabel              matlab.ui.control.Label
        dragswet                      matlab.ui.control.EditField
        W5Label_35                    matlab.ui.control.Label
        dragtab2                      matlab.ui.container.Tab
        Tabla36Button2                matlab.ui.control.Button
        dragtable                     matlab.ui.control.Table
        draglabel1                    matlab.ui.control.Label
        dragv51                       matlab.ui.control.EditField
        C_DLabel_5                    matlab.ui.control.Label
        dragv52                       matlab.ui.control.EditField
        Label_6                       matlab.ui.control.Label
        dragv41                       matlab.ui.control.EditField
        C_DLabel_4                    matlab.ui.control.Label
        dragv42                       matlab.ui.control.EditField
        Label_5                       matlab.ui.control.Label
        dragv31                       matlab.ui.control.EditField
        C_DLabel_3                    matlab.ui.control.Label
        dragv32                       matlab.ui.control.EditField
        Label_4                       matlab.ui.control.Label
        dragv22                       matlab.ui.control.EditField
        Label_3                       matlab.ui.control.Label
        dragv21                       matlab.ui.control.EditField
        C_DLabel_2                    matlab.ui.control.Label
        dragv12                       matlab.ui.control.EditField
        Label_2                       matlab.ui.control.Label
        dragv11                       matlab.ui.control.EditField
        C_DLabel                      matlab.ui.control.Label
        draglabel52                   matlab.ui.control.Label
        draglabel42                   matlab.ui.control.Label
        draglabel32                   matlab.ui.control.Label
        draglabel22                   matlab.ui.control.Label
        draglabel12                   matlab.ui.control.Label
        draglabel5                    matlab.ui.control.Label
        draglabel4                    matlab.ui.control.Label
        draglabel3                    matlab.ui.control.Label
        draglabel2                    matlab.ui.control.Label
        ClimbTab                      matlab.ui.container.Tab
        climbplotenable2              matlab.ui.control.CheckBox
        climbtakeofflabel             matlab.ui.control.Label
        climbappcl                    matlab.ui.control.EditField
        C_L_tomaxLabel_5              matlab.ui.control.Label
        climbwlwto                    matlab.ui.control.EditField
        C_L_tomaxLabel_4              matlab.ui.control.Label
        climblancl                    matlab.ui.control.EditField
        C_L_tomaxLabel_3              matlab.ui.control.Label
        climbercl                     matlab.ui.control.EditField
        C_L_tomaxLabel_2              matlab.ui.control.Label
        climbtocl                     matlab.ui.control.EditField
        C_L_tomaxLabel                matlab.ui.control.Label
        climbselect                   matlab.ui.control.DropDown
        ConfiguracinDropDownLabel     matlab.ui.control.Label
        climbapproachlabel            matlab.ui.control.Label
        climbplotenable               matlab.ui.control.CheckBox
        climbPlotButton               matlab.ui.control.Button
        climblabel6                   matlab.ui.control.Label
        climblabel5                   matlab.ui.control.Label
        climblandinglabel             matlab.ui.control.Label
        climbenroutelabel             matlab.ui.control.Label
        climblabel4                   matlab.ui.control.Label
        climbvs6                      matlab.ui.control.EditField
        climbvs5                      matlab.ui.control.EditField
        climbvs4                      matlab.ui.control.EditField
        climbtable                    matlab.ui.control.Table
        climbvslabel                  matlab.ui.control.Label
        climbvs3                      matlab.ui.control.EditField
        climbvs2                      matlab.ui.control.EditField
        climbvs1                      matlab.ui.control.EditField
        climblabel3                   matlab.ui.control.Label
        climblabel2                   matlab.ui.control.Label
        climblabel1                   matlab.ui.control.Label
        climbminus                    matlab.ui.control.Button
        climbplus                     matlab.ui.control.Button
        climbplot                     matlab.ui.control.CheckBox
        CruiseTab                     matlab.ui.container.Tab
        cruiset                       matlab.ui.control.EditField
        fracTW_reqcdotLabel_2         matlab.ui.control.Label
        cruisek                       matlab.ui.control.EditField
        fracTW_reqcdotLabel           matlab.ui.control.Label
        cruisev2                      matlab.ui.control.EditField
        Label_7                       matlab.ui.control.Label
        cruisev1                      matlab.ui.control.EditField
        fracTW_reqLabel               matlab.ui.control.Label
        cruisecd02                    matlab.ui.control.EditField
        rightarrowC_D0EditFieldLabel  matlab.ui.control.Label
        cruisedcd0                    matlab.ui.control.EditField
        DeltaC_D0EditFieldLabel       matlab.ui.control.Label
        cruisecd01                    matlab.ui.control.EditField
        C_D0EditFieldLabel            matlab.ui.control.Label
        cruiseq                       matlab.ui.control.EditField
        rightarrowqEditFieldLabel     matlab.ui.control.Label
        cruiseh                       matlab.ui.control.EditField
        hLabel                        matlab.ui.control.Label
        cruisem                       matlab.ui.control.EditField
        MEditFieldLabel               matlab.ui.control.Label
        cruiseplot                    matlab.ui.control.CheckBox
        cruiseplotenable              matlab.ui.control.CheckBox
        cruiseminus                   matlab.ui.control.Button
        cruiseplus                    matlab.ui.control.Button
        cruisePlotButton              matlab.ui.control.Button
        cruisetable                   matlab.ui.control.Table
        cruiselabel2                  matlab.ui.control.Label
        cruiselabel1                  matlab.ui.control.Label
        PTab                          matlab.ui.container.Tab
        AlasTab                       matlab.ui.container.Tab
        TabGroup6                     matlab.ui.container.TabGroup
        CuerdasyposicinflapsTab       matlab.ui.container.Tab
        wingx1                        matlab.ui.control.NumericEditField
        wingx2                        matlab.ui.control.NumericEditField
        wingSwf                       matlab.ui.control.NumericEditField
        S_wfLabel                     matlab.ui.control.Label
        x_1Label_2                    matlab.ui.control.Label
        x_1Label                      matlab.ui.control.Label
        wingTaper                     matlab.ui.control.NumericEditField
        SLabel_7                      matlab.ui.control.Label
        wingCtip                      matlab.ui.control.NumericEditField
        SLabel_6                      matlab.ui.control.Label
        wingCroot                     matlab.ui.control.NumericEditField
        SLabel_5                      matlab.ui.control.Label
        wingSweep                     matlab.ui.control.NumericEditField
        SLabel_4                      matlab.ui.control.Label
        wingb                         matlab.ui.control.NumericEditField
        SLabel_3                      matlab.ui.control.Label
        wingA                         matlab.ui.control.NumericEditField
        SLabel_2                      matlab.ui.control.Label
        wingS                         matlab.ui.control.NumericEditField
        SLabel                        matlab.ui.control.Label
        wingPlot                      matlab.ui.control.UIAxes
        FlapsTab                      matlab.ui.container.Tab
        DisponibleLabel               matlab.ui.control.Label
        RequeridoLabel                matlab.ui.control.Label
        liftgradient                  matlab.ui.control.NumericEditField
        Label_11                      matlab.ui.control.Label
        Deltac_lalpha_flapLabel       matlab.ui.control.Label
        liftmultigradient             matlab.ui.control.CheckBox
        delta_fLabel                  matlab.ui.control.Label
        liftmultideltaflap            matlab.ui.control.CheckBox
        liftPlot                      matlab.ui.control.Button
        cdotfrac1KLabel               matlab.ui.control.Label
        liftreqDeltaCl0TO             matlab.ui.control.NumericEditField
        C_LmaxLabel_9                 matlab.ui.control.Label
        liftreqDeltaCl0L              matlab.ui.control.NumericEditField
        C_LmaxLabel_8                 matlab.ui.control.Label
        liftK                         matlab.ui.control.NumericEditField
        Label_10                      matlab.ui.control.Label
        cfcDropDown                   matlab.ui.control.DropDown
        cfcDropDownLabel              matlab.ui.control.Label
        liftKsweep                    matlab.ui.control.NumericEditField
        Label_9                       matlab.ui.control.Label
        liftreqDeltaClmaxTO           matlab.ui.control.NumericEditField
        C_LmaxLabel_7                 matlab.ui.control.Label
        liftreqDeltaClmaxL            matlab.ui.control.NumericEditField
        C_LmaxLabel_6                 matlab.ui.control.Label
        cdotfracSS_wfK_LambdaLabel    matlab.ui.control.Label
        maxTOLmaxcdot105Label         matlab.ui.control.Label
        liftreqDeltaCLmaxL            matlab.ui.control.NumericEditField
        C_LmaxLabel_5                 matlab.ui.control.Label
        liftreqDeltaCLmaxTO           matlab.ui.control.NumericEditField
        C_LmaxLabel_4                 matlab.ui.control.Label
        liftreqCLmaxL                 matlab.ui.control.NumericEditField
        C_LmaxLabel_3                 matlab.ui.control.Label
        liftreqCLmaxTO                matlab.ui.control.NumericEditField
        C_LmaxLabel_2                 matlab.ui.control.Label
        liftreqCLmax                  matlab.ui.control.NumericEditField
        C_LmaxLabel                   matlab.ui.control.Label
        EmpenajeTab                   matlab.ui.container.Tab
        empPlot                       matlab.ui.control.Button
        empHorA                       matlab.ui.control.NumericEditField
        empHorTaper                   matlab.ui.control.NumericEditField
        lambdaEditFieldLabel          matlab.ui.control.Label
        empHorSweep                   matlab.ui.control.NumericEditField
        LambdaEditFieldLabel          matlab.ui.control.Label
        empHorIHT                     matlab.ui.control.NumericEditField
        I_HTEditFieldLabel            matlab.ui.control.Label
        empHorHHT                     matlab.ui.control.NumericEditField
        H_HTEditFieldLabel            matlab.ui.control.Label
        empVerA                       matlab.ui.control.NumericEditField
        AEditField_2Label             matlab.ui.control.Label
        empVerTaper                   matlab.ui.control.NumericEditField
        empVerSweep                   matlab.ui.control.NumericEditField
        empVerIVT                     matlab.ui.control.NumericEditField
        I_VTEditFieldLabel            matlab.ui.control.Label
        empVerHVT                     matlab.ui.control.NumericEditField
        H_VTEditFieldLabel            matlab.ui.control.Label
        EstabilizadorverticalLabel    matlab.ui.control.Label
        lambdaEditField_2Label        matlab.ui.control.Label
        LambdaEditField_2Label        matlab.ui.control.Label
        ALabel                        matlab.ui.control.Label
        EstabilizadorhorizontalLabel  matlab.ui.control.Label
        CentrodegravedadTab           matlab.ui.container.Tab
        TabGroup7                     matlab.ui.container.TabGroup
        EjesTab                       matlab.ui.container.Tab
        refcmac1                      matlab.ui.control.NumericEditField
        y_0Label_3                    matlab.ui.control.Label
        refcmac0                      matlab.ui.control.NumericEditField
        y_0Label_2                    matlab.ui.control.Label
        refplot                       matlab.ui.control.Button
        refy0                         matlab.ui.control.NumericEditField
        y_0Label                      matlab.ui.control.Label
        refx1                         matlab.ui.control.NumericEditField
        x_1Label_3                    matlab.ui.control.Label
        refx0                         matlab.ui.control.NumericEditField
        x_0EditFieldLabel             matlab.ui.control.Label
        ComponentesTab                matlab.ui.container.Tab
        cgsum                         matlab.ui.control.CheckBox
        cgtotal                       matlab.ui.control.NumericEditField
        PesototalEditFieldLabel       matlab.ui.control.Label
        cgall                         matlab.ui.control.CheckBox
        cgplot                        matlab.ui.control.Button
        cgcatminus                    matlab.ui.control.Button
        cgcatplus                     matlab.ui.control.Button
        cgname                        matlab.ui.control.EditField
        NombreEditFieldLabel          matlab.ui.control.Label
        cgdrop                        matlab.ui.control.DropDown
        CategoraDropDownLabel         matlab.ui.control.Label
        cgminus                       matlab.ui.control.Button
        cgplus                        matlab.ui.control.Button
        cgtable                       matlab.ui.control.Table
        DiagramaTab                   matlab.ui.container.Tab
        explot                        matlab.ui.control.Button
        extminus                      matlab.ui.control.Button
        extplus                       matlab.ui.control.Button
        exttable                      matlab.ui.control.Table
        save_new                      matlab.ui.control.Button
        filename                      matlab.ui.control.Label
        load                          matlab.ui.control.Button
        save                          matlab.ui.control.Button
    end

    properties (Access = private)
        table21 = [
            [0.998, 0.998, 0.998, 0.995, 0.995];
            [0.995, 0.997, 0.998, 0.993, 0.993];
            [0.992, 0.996, 0.996, 0.992, 0.992];
            [0.996, 0.995, 0.996, 0.999, 0.998];
            [0.990, 0.995, 0.995, 0.990, 0.992];
            [0.990, 0.995, 0.995, 0.985, 0.995];
            [0.990, 0.990, 0.995, 0.990, 0.992];
            [0.990, 0.990, 0.990, 0.990, 0.995];
            [0.990, 0.990, 0.990, 0.990, 0.995];
            [0.990, 0.990, 0.995, 0.990, 0.992];
            [0.992, 0.990, 0.996, 0.990, 0.990];
            [0.990, 0.995, 0.995, 0.985, 0.992]
        ];
        table29 = transpose([
            % BOEING
            [184800, 100000];
            [112500, 61630];
            [124500, 69930];
            [775000, 380000];
            [630000, 325000];
            [220000, 130420];
            [300000, 179082];
            % McDONNELL-DOUGLAS
            [325000, 162700];
            [121000, 57190];
            [140000, 79757];
            [455000, 244903];
            [555000, 271062];
            [510000, 245500];
            [73000, 38683];
            [99650, 53762];
            [44000, 26850];
            [89500, 48500];
            % AIRBUS
            [363760, 195109];
            [291000, 168910];
            [357150, 153000];
            [198416, 95900]
        ]);
        tablecf = [
            [0.002, -2.6990, 1];
            [0.003, -2.5229, 1];
            [0.004, -2.3979, 1];
            [0.005, -2.3010, 1];
            [0.006, -2.2218, 1];
            [0.007, -2.1549, 1];
            [0.008, -2.0969, 1];
            [0.009, -2.0458, 1]
        ];
        prop = true;
        file = "";
        lasttoselection = 1;
        laststallselection = 1;
        lastlanselection = 1;
        lastcruiseselection = 1;
        cgsel = 1;
        rho0;
        climbinput = [
            [0, 0, 0, 0, 0, 0, 1];
        ];
        climboutput = [zeros(6, 4, 1) * nan, [1;1;1;1;1;1]];
        cruiseoutput = [zeros(1, 4) * nan];
        catvalues = [zeros(6, 3)];
        catstart = [1, 3, 4, 5, 6];
        catnames = ["cat1", "cat2", "cat3", "cat4", "cat5"];
        compnames = ["a", "b", "c", "d", "e", "f"];
        exlist = ["1", "1"];
        exw = [0, 0];
    end
    methods (Access = private)
        function Tabla22Figure(app)
            f = figure(3);
            f.MenuBar = 'none';
            f.Name = 'Tabla 2.2';
            imshow('tabla22.png');
            set(gca,'units','pixels');
            x = get(gca,'position');
            set(gcf,'units','pixels');
            y = get(gcf,'position');
            set(gcf,'position',[y(1) y(2) x(3) x(4)]);
            set(gca,'units','normalized','position',[0 0 1 1]);
        end
        function Tabla31Figure(app)
            f = figure(4);
            f.MenuBar = 'none';
            f.Name = 'Tabla 3.1';
            imshow('tabla31.png');
            set(gca,'units','pixels');
            x = get(gca,'position');
            set(gcf,'units','pixels');
            y = get(gcf,'position');
            set(gcf,'position',[y(1) y(2) x(3) x(4)]);
            set(gca,'units','normalized','position',[0 0 1 1]); 
        end
        function Tabla36Figure(app)
            f = figure(6);
            f.MenuBar = 'none';
            f.Name = 'Tabla 3.6';
            imshow('tabla36.png');
            set(gca,'units','pixels');
            x = get(gca,'position');
            set(gcf,'units','pixels');
            y = get(gcf,'position');
            set(gcf,'position',[y(1) y(2) x(3) x(4)]);
            set(gca,'units','normalized','position',[0 0 1 1]); 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.init();
        end

        % Value changed function: tipo
        function tipoValueChanged(app, event)
            app.updateUIPlaneType();
        end

        % Value changed function: W1TO
        function W1TOValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: W21
        function W21ValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: W32
        function W32ValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: W76
        function W76ValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: W87
        function W87ValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: W98
        function W98ValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: E_cl
        function E_clValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: V_cl
        function V_clValueChanged(app, event)
           app.A1_FuelFraction();
        end

        % Value changed function: R_cr
        function R_crValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: c_cl
        function c_clValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: n_p_cl
        function n_p_clValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: LD_cl
        function LD_clValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: V_cr
        function V_crValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: c_cr
        function c_crValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: n_p_cr
        function n_p_crValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: LD_cr
        function LD_crValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: V_lo
        function V_loValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: c_lo
        function c_loValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: n_p_lo
        function n_p_loValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: LD_lo
        function LD_loValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: E_lo
        function E_loValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Value changed function: W_to
        function W_toValueChanged(app, event)
            app.A1_FuelFraction();
        end

        % Button pushed function: save_new
        function save_newButtonPushed(app, event)
            app.saveFile(1);
        end

        % Button pushed function: load
        function loadButtonPushed(app, event)
            app.loadFile("");
        end

        % Button pushed function: save
        function saveButtonPushed(app, event)
            app.saveFile(0);
        end

        % Value changed function: Wf_res
        function Wf_resValueChanged(app, event)
            app.A2_TakeOffWeight();
        end

        % Value changed function: Wpl
        function WplValueChanged(app, event)
            app.A2_TakeOffWeight();
        end

        % Value changed function: Wcrew
        function WcrewValueChanged(app, event)
            app.A2_TakeOffWeight();
        end

        % Button pushed function: WPlotButton
        function WPlotButtonPushed(app, event)
            app.A_WeightPlot();
        end

        % Button pushed function: Tabla21Button
        function Tabla21ButtonPushed(app, event)
            f = figure(1);
            f.MenuBar = 'none';
            f.Name = 'Tabla 2.1';
            imshow('tabla21.png');
            set(gca,'units','pixels');
            x = get(gca,'position');
            set(gcf,'units','pixels');
            y = get(gcf,'position');
            set(gcf,'position',[y(1) y(2) x(3) x(4)]);
            set(gca,'units','normalized','position',[0 0 1 1]);
        end

        % Button pushed function: Tabla22Button1
        function Tabla22Button1Pushed(app, event)
            app.Tabla22Figure();
        end

        % Button pushed function: Tabla22Button2
        function Tabla22Button2Pushed(app, event)
            app.Tabla22Figure();
        end

        % Button pushed function: Tabla22Button3
        function Tabla22Button3Pushed(app, event)
            app.Tabla22Figure();
        end

        % Button pushed function: Tabla31Button1
        function Tabla31Button1Pushed(app, event)
            app.Tabla31Figure();
        end

        % Button pushed function: Tabla31Button2
        function Tabla31Button2Pushed(app, event)
            app.Tabla31Figure();
        end

        % Button pushed function: Tabla31Button3
        function Tabla31Button3Pushed(app, event)
            app.Tabla31Figure();
        end

        % Button pushed function: Tabla36Button1
        function Tabla36Button1Pushed(app, event)
            app.Tabla36Figure();
        end

        % Button pushed function: Tabla36Button2
        function Tabla36Button2Pushed(app, event)
            app.Tabla36Figure();
        end

        % Selection changed function: stalltable
        function stalltableSelectionChanged(app, event)
            if app.stalltable.Selection >= 1
            else
                app.stalltable.Selection = app.laststallselection;
            end
            app.laststallselection = app.stalltable.Selection;
            i = app.laststallselection;
            app.stallvs.Value = num2str(app.stalltable.Data(i,1));
            app.stallheight.Value = num2str(app.stalltable.Data(i,2));
            app.stallcl.Value = num2str(app.stalltable.Data(i,3));
            app.stallplot.Value = app.stalltable.Data(i, 5);
            app.B1_StallPartialResults();
        end

        % Selection changed function: totable
        function totableSelectionChanged(app, event)
            if app.totable.Selection >= 1
            else
                app.totable.Selection = app.lasttoselection;
            end
            app.lasttoselection = app.totable.Selection;
            i = app.lasttoselection;
            app.toStofl.Value = num2str(app.totable.Data(i,1));
            app.toheight.Value = num2str(app.totable.Data(i,2));
            app.toclmax.Value = num2str(app.totable.Data(i,3));
            app.toplot.Value = app.totable.Data(i, 4);
            app.B2_TakeOffPartialResults();
        end

        % Selection changed function: lantable
        function lantableSelectionChanged(app, event)
            if app.lantable.Selection >= 1
            else
                app.lantable.Selection = app.lastlanselection;
            end
            app.lastlanselection = app.lantable.Selection;
            i = app.lastlanselection;
            app.lansl.Value = num2str(app.lantable.Data(i,1));
            app.lanheight.Value = num2str(app.lantable.Data(i,2));
            app.lancl.Value = num2str(app.lantable.Data(i,3));
            app.lanrel.Value = num2str(app.lantable.Data(i,4));
            app.lanplot.Value = app.lantable.Data(i, 5);
            app.B3_LandingPartialResults();
        end

        % Selection changed function: cruisetable
        function cruisetableSelectionChanged(app, event)
            if app.cruisetable.Selection >= 1
            else
                app.cruisetable.Selection = app.lastcruiseselection;
            end
            app.lastcruiseselection = app.cruisetable.Selection;
            i = app.lastcruiseselection;
            app.cruisem.Value = num2str(app.cruisetable.Data(i,1));
            app.cruiseh.Value = num2str(app.cruisetable.Data(i,2));
            app.cruisedcd0.Value = num2str(app.cruisetable.Data(i,3));
            app.cruisek.Value = num2str(app.cruisetable.Data(i,4));
            app.cruiset.Value = num2str(app.cruisetable.Data(i,5));
            app.cruiseplot.Value = app.cruisetable.Data(i,6);
            app.B5_Cruise();
        end

        % Button pushed function: stallplus
        function stallplusButtonPushed(app, event)
            i = app.laststallselection;
            app.stalltable.Data = [app.stalltable.Data; app.stalltable.Data(i,:)];
            app.stalltable.Selection = height(app.stalltable.Data);
            app.stalltableSelectionChanged(1);
            if ishandle(5)
                app.B_TWWSPlot()
            end
        end

        % Button pushed function: toplus
        function toplusButtonPushed(app, event)
            i = app.lasttoselection;
            app.totable.Data = [app.totable.Data; app.totable.Data(i,:)];
            app.totable.Selection = height(app.totable.Data);
            app.totableSelectionChanged(1);
            if ishandle(5)
                app.B_TWWSPlot()
            end
        end

        % Button pushed function: lanplus
        function lanplusButtonPushed(app, event)
            i = app.lastlanselection;
            app.lantable.Data = [app.lantable.Data; app.lantable.Data(i,:)];
            app.lantable.Selection = height(app.lantable.Data);
            app.lantableSelectionChanged(1);
            if ishandle(5)
                app.B_TWWSPlot()
            end
        end

        % Button pushed function: climbplus
        function climbplusButtonPushed(app, event)
            len = length(app.climbselect.Items);
            app.climbselect.Items = [app.climbselect.Items, {num2str(len+1)}];
            i = str2double(app.climbselect.Value);
            app.climboutput(:,:,len+1) = app.climboutput(:,:,i);
            app.climbinput(len+1,:) = app.climbinput(i,:);
            app.climbselect.Value = num2str(len+1);
        end

        % Button pushed function: cruiseplus
        function cruiseplusButtonPushed(app, event)
            i = app.lastcruiseselection;
            app.cruisetable.Data = [app.cruisetable.Data; app.cruisetable.Data(i,:)];
            app.cruiseoutput = [app.cruiseoutput; app.cruiseoutput(i,:)];
            app.cruisetable.Selection = height(app.cruisetable.Data);
            app.cruisetableSelectionChanged(1);
        end

        % Button pushed function: stallminus
        function stallminusButtonPushed(app, event)
            if height(app.stalltable.Data) > 1
                i = app.laststallselection;
                app.stalltable.Data(i,:) = [];
                if i > 1
                    app.stalltable.Selection = i-1;
                end
                app.stalltableSelectionChanged(1);
            end
            if ishandle(5)
                app.B_TWWSPlot()
            end
        end

        % Button pushed function: tominus
        function tominusButtonPushed(app, event)
            if height(app.totable.Data) > 1
                i = app.lasttoselection;
                app.totable.Data(i,:) = [];
                if i > 1
                    app.totable.Selection = i-1;
                end
                app.totableSelectionChanged(1);
            end
            if ishandle(5)
                app.B_TWWSPlot()
            end
        end

        % Button pushed function: lanminus
        function lanminusButtonPushed(app, event)
            if height(app.lantable.Data) > 1
                i = app.lastlanselection;
                app.lantable.Data(i,:) = [];
                if i > 1
                    app.lantable.Selection = i-1;
                end
                app.lantableSelectionChanged(1);
            end
            if ishandle(5)
                app.B_TWWSPlot()
            end
        end

        % Button pushed function: climbminus
        function climbminusButtonPushed(app, event)
            len = size(app.climboutput, 3);
            if len > 1
                i = str2double(app.climbselect.Value);
                app.climbinput(i,:) = [];
                app.climboutput(:,:,i) = [];
                app.climbselect.Items = string(1:len-1);
                if i > 1
                    app.climbselect.Value = num2str(i - 1);
                end
                app.B42_Climb();
            end
        end

        % Button pushed function: cruiseminus
        function cruiseminusButtonPushed(app, event)
            if height(app.cruisetable.Data) > 1
                i = app.lastcruiseselection;
                app.cruisetable.Data(i,:) = [];
                app.cruiseoutput(i,:) = [];
                if i > 1
                    app.cruisetable.Selection = i-1;
                end
                app.cruisetableSelectionChanged(1);
            end
            if ishandle(5)
                app.B_TWWSPlot()
            end
        end

        % Value changed function: stallvs
        function stallvsValueChanged(app, event)
            app.B1_StallTable();
        end

        % Value changed function: stallheight
        function stallheightValueChanged(app, event)
            app.B1_StallTable();
        end

        % Value changed function: stallcl
        function stallclValueChanged(app, event)
            app.B1_StallTable();
        end

        % Value changed function: toStofl
        function toStoflValueChanged(app, event)
            app.B2_TakeOffTable();
        end

        % Value changed function: toheight
        function toheightValueChanged(app, event)
            app.B2_TakeOffTable();
        end

        % Value changed function: toclmax
        function toclmaxValueChanged(app, event)
            app.B2_TakeOffTable();
        end

        % Value changed function: lansl
        function lanslValueChanged(app, event)
            app.B3_LandingTable();
        end

        % Value changed function: lanrel
        function lanrelValueChanged(app, event)
            app.B3_LandingTable();
        end

        % Value changed function: lanheight
        function lanheightValueChanged(app, event)
            app.B3_LandingTable();
        end

        % Value changed function: lancl
        function lanclValueChanged(app, event)
            app.B3_LandingTable();
        end

        % Button pushed function: stallPlotButton
        function stallPlotButtonPushed(app, event)
            app.B_TWWSPlot();
        end

        % Button pushed function: toPlotButton
        function toPlotButtonPushed(app, event)
            app.B_TWWSPlot();
        end

        % Button pushed function: lanPlotButton
        function lanPlotButtonPushed(app, event)
            app.B_TWWSPlot();
        end

        % Button pushed function: climbPlotButton
        function climbPlotButtonPushed(app, event)
            app.B_TWWSPlot();
        end

        % Button pushed function: cruisePlotButton
        function cruisePlotButtonPushed(app, event)
            app.B_TWWSPlot();
        end

        % Value changed function: stallplot
        function stallplotValueChanged(app, event)
            app.B1_StallTable();
        end

        % Value changed function: toplot
        function toplotValueChanged(app, event)
            app.B2_TakeOffTable();
        end

        % Value changed function: lanplot
        function lanplotValueChanged(app, event)
            app.B3_LandingTable();
        end

        % Value changed function: stallplotenable
        function stallplotenableValueChanged(app, event)
            if ishandle(5)
                app.B_TWWSPlot();
            end
        end

        % Value changed function: toplotenable
        function toplotenableValueChanged(app, event)
            if ishandle(5)
                app.B_TWWSPlot();
            end
        end

        % Value changed function: lanplotenable
        function lanplotenableValueChanged(app, event)
            if ishandle(5)
                app.B_TWWSPlot();
            end
        end

        % Value changed function: climbplotenable
        function climbplotenableValueChanged(app, event)
            if ishandle(5)
                app.B_TWWSPlot();
            end
        end

        % Value changed function: climbplotenable2
        function climbplotenable2ValueChanged(app, event)
            if ishandle(5)
                app.B_TWWSPlot();
            end
        end

        % Value changed function: cruiseplotenable
        function cruiseplotenableValueChanged(app, event)
            if ishandle(5)
                app.B_TWWSPlot();
            end
        end

        % Value changed function: dragcf
        function dragcfValueChanged(app, event)
            app.B41_DragPolar();
        end

        % Value changed function: drags
        function dragsValueChanged(app, event)
            app.B41_DragPolar();
        end

        % Value changed function: draga
        function dragaValueChanged(app, event)
            app.B41_DragPolar();
        end

        % Value changed function: drage
        function drageValueChanged(app, event)
            app.B41_DragPolar();
        end

        % Value changed function: dragswet
        function dragswetValueChanged(app, event)
            app.B41_DragPolar();
        end

        % Cell edit callback: dragtable
        function dragtableCellEdit(app, event)
            app.dragtable.Data(3,2) = nan;
            app.B41_DragPolar();
        end

        % Value changed function: climbtocl
        function climbtoclValueChanged(app, event)
            i = str2double(app.climbselect.Value);
            app.climbinput(i,1) = str2double(app.climbtocl.Value);
            app.B42_Climb();
        end

        % Value changed function: climbercl
        function climberclValueChanged(app, event)
            i = str2double(app.climbselect.Value);
            app.climbinput(i,2) = str2double(app.climbercl.Value);
            app.B42_Climb();
        end

        % Value changed function: climblancl
        function climblanclValueChanged(app, event)
            i = str2double(app.climbselect.Value);
            app.climbinput(i,3) = str2double(app.climblancl.Value);
            app.B42_Climb();
        end

        % Value changed function: climbvs2
        function climbvs2ValueChanged(app, event)
            i = str2double(app.climbselect.Value);
            app.climbinput(i,6) = str2double(app.climbvs2.Value);
            app.B42_Climb();
        end

        % Value changed function: climbwlwto
        function climbwlwtoValueChanged(app, event)
            i = str2double(app.climbselect.Value);
            app.climbinput(i,4) = str2double(app.climbwlwto.Value);
            app.B42_Climb();
        end

        % Value changed function: climbappcl
        function climbappclValueChanged(app, event)
            i = str2double(app.climbselect.Value);
            app.climbinput(i,5) = str2double(app.climbappcl.Value);
            app.B42_Climb();
        end

        % Value changed function: climbselect
        function climbselectValueChanged(app, event)
            app.B42_Climb();
        end

        % Value changed function: climbplot
        function climbplotValueChanged(app, event)
            i = str2double(app.climbselect.Value);
            app.climbinput(i,7) = app.climbplot.Value;
            app.B42_Climb();
        end

        % Cell edit callback: climbtable
        function climbtableCellEdit(app, event)
            i = str2double(app.climbselect.Value);
            app.climboutput(:,5,i) = app.climbtable.Data(:,5);
            if ishandle(5)
                app.B_TWWSPlot();
            end
        end

        % Value changed function: cruisem
        function cruisemValueChanged(app, event)
            app.B5_Cruise();
        end

        % Value changed function: cruiseh
        function cruisehValueChanged(app, event)
            app.B5_Cruise();
        end

        % Value changed function: cruisedcd0
        function cruisedcd0ValueChanged(app, event)
            app.B5_Cruise();
        end

        % Value changed function: cruisek
        function cruisekValueChanged(app, event)
            app.B5_Cruise();
        end

        % Value changed function: cruiset
        function cruisetValueChanged(app, event)
            app.B5_Cruise();
        end

        % Value changed function: cruiseplot
        function cruiseplotValueChanged(app, event)
            app.B5_Cruise();
        end

        % Value changed function: wingSweep
        function wingSweepValueChanged(app, event)
            app.C1_ChordsFlaps();
        end

        % Value changed function: wingTaper
        function wingTaperValueChanged(app, event)
            app.C1_ChordsFlaps();
        end

        % Value changed function: wingx1
        function wingx1ValueChanged(app, event)
            app.C1_ChordsFlaps();
        end

        % Value changed function: wingx2
        function wingx2ValueChanged(app, event)
            app.C1_ChordsFlaps();
        end

        % Button pushed function: liftPlot
        function liftPlotButtonPushed(app, event)
            app.C_LiftAvailablePlot();
        end

        % Value changed function: liftgradient
        function liftgradientValueChanged(app, event)
            if ishandle(6)
                app.C_LiftAvailablePlot()
            end
        end

        % Value changed function: liftreqCLmax
        function liftreqCLmaxValueChanged(app, event)
            app.C1_ChordsFlaps();
        end

        % Value changed function: cfcDropDown
        function cfcDropDownValueChanged(app, event)
            app.C1_ChordsFlaps();
        end

        % Value changed function: liftmultideltaflap
        function liftmultideltaflapValueChanged(app, event)
            if ishandle(6)
                app.C_LiftAvailablePlot()
            end
        end

        % Value changed function: liftmultigradient
        function liftmultigradientValueChanged(app, event)
            if ishandle(6)
                app.C_LiftAvailablePlot()
            end
        end

        % Value changed function: liftK
        function liftKValueChanged(app, event)
            app.C1_ChordsFlaps();
        end

        % Button pushed function: empPlot
        function empPlotButtonPushed(app, event)
            app.C_EmpennagePlot();
        end

        % Value changed function: empHorA
        function empHorAValueChanged(app, event)
            app.C3_Empennage();
        end

        % Value changed function: empHorTaper
        function empHorTaperValueChanged(app, event)
            app.C3_Empennage();
        end

        % Value changed function: empHorSweep
        function empHorSweepValueChanged(app, event)
            app.C3_Empennage();
        end

        % Value changed function: empHorIHT
        function empHorIHTValueChanged(app, event)
            app.C3_Empennage();
        end

        % Value changed function: empHorHHT
        function empHorHHTValueChanged(app, event)
            app.C3_Empennage();
        end

        % Value changed function: empVerA
        function empVerAValueChanged(app, event)
            app.C3_Empennage();
        end

        % Value changed function: empVerTaper
        function empVerTaperValueChanged(app, event)
            app.C3_Empennage();
        end

        % Value changed function: empVerSweep
        function empVerSweepValueChanged(app, event)
            app.C3_Empennage();
        end

        % Value changed function: empVerIVT
        function empVerIVTValueChanged(app, event)
            app.C3_Empennage();
        end

        % Value changed function: empVerHVT
        function empVerHVTValueChanged(app, event)
            app.C3_Empennage();
        end

        % Button pushed function: refplot
        function refplotButtonPushed(app, event)
            app.D_AxesPlot();
        end

        % Value changed function: cgdrop
        function cgdropValueChanged(app, event)
            for i = 1:length(app.catnames)
                if app.cgdrop.Value == app.catnames(i)
                    app.cgsel = i;
                    break
                end
            end
            app.D2_Categories();
        end

        % Button pushed function: cgcatplus
        function cgcatplusButtonPushed(app, event)
            app.catnames = [app.catnames, "Nueva"];
            app.catstart = [app.catstart, size(app.catvalues, 1) + 1];
            app.catvalues = [app.catvalues; [1, 1, 1]];
            app.compnames = [app.compnames, "Nueva"];
            app.cgsel = length(app.catnames);
            app.D2_Categories();
        end

        % Value changed function: cgname
        function cgnameValueChanged(app, event)
            app.catnames(app.cgsel) = app.cgname.Value;
            app.D2_Categories();
        end

        % Cell edit callback: cgtable
        function cgtableCellEdit(app, event)
            indices = event.Indices;
            indices(1) = indices(1) + app.catstart(app.cgsel) - 1;
            newData = event.NewData;
            if indices(2) == 1
                app.compnames(indices(1)) = newData;
            else
                app.catvalues(indices(1), indices(2) - 1) = newData;
            end
            app.D2_Categories();
        end

        % Button pushed function: cgplus
        function cgplusButtonPushed(app, event)
            app.catstart(app.cgsel+1:end) = app.catstart(app.cgsel+1:end) + 1;
            if app.cgsel < length(app.catstart)
                i = app.catstart(app.cgsel+1) - 1;
                app.compnames(i+1:end+1) = app.compnames(i:end);
                app.catvalues(i+1:end+1,:) = app.catvalues(i:end,:);
                app.compnames(i) = "Nuevo";
                app.catvalues(i,:) = [0, 0, 0];
            else
                app.compnames(end+1) = "Nuevo";
                app.catvalues(end+1,:) = [0, 0, 0];
            end
            app.D2_Categories();
        end

        % Button pushed function: cgcatminus
        function cgcatminusButtonPushed(app, event)
            if length(app.catstart) > 1
                app.catnames(app.cgsel) = [];
                s = app.catstart(app.cgsel);
                if app.cgsel<length(app.catstart)
                    e = app.catstart(app.cgsel+1) - 1;
                else
                    e = size(app.catvalues, 1);
                end
                el = e-s+1;
                app.catstart(app.cgsel) = [];
                app.catstart(app.cgsel:end) = app.catstart(app.cgsel:end) - el;
                app.compnames(s:e) = [];
                app.catvalues(s:e,:) = [];
                if app.cgsel > 1
                    app.cgsel = app.cgsel - 1;
                end
                app.cgdrop.Items(app.cgsel) = [];
                app.D2_Categories();
            end
        end

        % Button pushed function: cgminus
        function cgminusButtonPushed(app, event)
            if size(app.cgtable.Data, 1) == 1
                return
            end
            sel = app.cgtable.Selection;
            if sel
                i = app.catstart(app.cgsel)-1+sel(1);
                app.compnames(i) = [];
                app.catvalues(i,:) = [];
                app.catstart(app.cgsel+1:end) = app.catstart(app.cgsel+1:end) - 1;
                app.D2_Categories();
            end
        end

        % Cell edit callback: exttable
        function exttableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.exlist(indices(1)) = newData;
            app.D3_Stages();
        end

        % Button pushed function: extplus
        function extplusButtonPushed(app, event)
            app.exlist(end+1) = "1";
            app.D3_Stages();
        end

        % Button pushed function: extminus
        function extminusButtonPushed(app, event)
            if length(app.exlist) == 1
                return
            end
            app.exlist(end) = [];
            app.D3_Stages();
        end

        % Button pushed function: cgplot
        function cgplotButtonPushed(app, event)
            app.D_ComponentPlot();
        end

        % Value changed function: cgall
        function cgallValueChanged(app, event)
            if ishandle(10)
                app.D_ComponentPlot();
            end
        end

        % Value changed function: cgsum
        function cgsumValueChanged(app, event)
            if ishandle(10)
                app.D_ComponentPlot();
            end
        end

        % Value changed function: refx0
        function refx0ValueChanged(app, event)
            if ishandle(9)
                app.D_AxesPlot();
            end
        end

        % Value changed function: refx1
        function refx1ValueChanged(app, event)
            if ishandle(9)
                app.D_AxesPlot();
            end
        end

        % Value changed function: refy0
        function refy0ValueChanged(app, event)
            if ishandle(9)
                app.D_AxesPlot();
            end
        end

        % Button pushed function: explot
        function explotButtonPushed(app, event)
            app.D_ExcursionPlot();
        end

        % Value changed function: refcmac0
        function refcmac0ValueChanged(app, event)
            if ishandle(9)
                app.D_AxesPlot();
            end
        end

        % Value changed function: refcmac1
        function refcmac1ValueChanged(app, event)
            if ishandle(9)
                app.D_AxesPlot();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.save = uibutton(app.UIFigure, 'push');
            app.save.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.save.Text = 'Guardar';
            app.load = uibutton(app.UIFigure, 'push');
            app.load.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.load.Text = 'Cargar';
            app.filename = uilabel(app.UIFigure);
            app.filename.Text = 'Ningún archivo cargado';
            app.save_new = uibutton(app.UIFigure, 'push');
            app.save_new.ButtonPushedFcn = createCallbackFcn(app, @save_newButtonPushed, true);
            app.save_new.Text = 'Guardar nuevo';
            app.TabGroup3 = uitabgroup(app.UIFigure);
            app.ResumenTab = uitab(app.TabGroup3);
            app.ResumenTab.Title = 'Resumen';
            app.Label = uilabel(app.ResumenTab);
            app.TipodeaeronaveDropDownLabel = uilabel(app.ResumenTab);
            app.TipodeaeronaveDropDownLabel.Text = 'Tipo de aeronave';
            app.tipo = uidropdown(app.ResumenTab);
            app.tipo.Items = {'Homebuilt Propeller Driven Airplanes', 'Single Engine Propeller Airplanes', 'Twin Engine Propeller Airplanes', 'Agricultural Airplanes', 'Business Jets', 'Regional Turbopropeller Driven Airplanes', 'Transport Jets', 'Military Trainers', 'Fighters', 'Military Patrol, Bomb and Transport Airplanes', 'Flying Boats, Amphibious and Float Airplanes', 'Supersonic Cruise Airplanes'};
            app.tipo.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.tipo.ValueChangedFcn = createCallbackFcn(app, @tipoValueChanged, true);
            app.tipo.Enable = 'off';
            app.tipo.Value = '7';
            app.PesoenvacoTab = uitab(app.TabGroup3);
            app.PesoenvacoTab.Title = 'Peso en vacío';
            app.TabGroup5 = uitabgroup(app.PesoenvacoTab);
            app.FraccindecombustibleTab = uitab(app.TabGroup5);
            app.FraccindecombustibleTab.Title = 'Fracción de combustible';
            app.Tabla21Button = uibutton(app.FraccindecombustibleTab, 'push');
            app.Tabla21Button.ButtonPushedFcn = createCallbackFcn(app, @Tabla21ButtonPushed, true);
            app.Tabla21Button.Text = 'Tabla 2.1';
            app.W_1Label = uilabel(app.FraccindecombustibleTab);
            app.W_1Label.Interpreter = 'latex';
            app.W_1Label.Text = '$W_{1/TO}$';
            app.W1TO = uieditfield(app.FraccindecombustibleTab, 'text');
            app.W1TO.ValueChangedFcn = createCallbackFcn(app, @W1TOValueChanged, true);
            app.W2Label = uilabel(app.FraccindecombustibleTab);
            app.W2Label.Interpreter = 'latex';
            app.W2Label.Text = '$W_{2/1}$';
            app.W21 = uieditfield(app.FraccindecombustibleTab, 'text');
            app.W21.ValueChangedFcn = createCallbackFcn(app, @W21ValueChanged, true);
            app.W3Label = uilabel(app.FraccindecombustibleTab);
            app.W3Label.Interpreter = 'latex';
            app.W3Label.Text = '$W_{3/2}$';
            app.W32 = uieditfield(app.FraccindecombustibleTab, 'text');
            app.W32.ValueChangedFcn = createCallbackFcn(app, @W32ValueChanged, true);
            app.W4Label = uilabel(app.FraccindecombustibleTab);
            app.W4Label.Interpreter = 'latex';
            app.W4Label.Text = '$W_{4/3}$';
            app.W43 = uieditfield(app.FraccindecombustibleTab, 'text');
            app.W43.Editable = 'off';
            app.W5Label = uilabel(app.FraccindecombustibleTab);
            app.W5Label.Interpreter = 'latex';
            app.W5Label.Text = '$W_{5/4}$';
            app.W54 = uieditfield(app.FraccindecombustibleTab, 'text');
            app.W54.Editable = 'off';
            app.W6Label = uilabel(app.FraccindecombustibleTab);
            app.W6Label.Interpreter = 'latex';
            app.W6Label.Text = '$W_{6/5}$';
            app.W65 = uieditfield(app.FraccindecombustibleTab, 'text');
            app.W65.Editable = 'off';
            app.W7Label = uilabel(app.FraccindecombustibleTab);
            app.W7Label.Interpreter = 'latex';
            app.W7Label.Text = '$W_{7/6}$';
            app.W76 = uieditfield(app.FraccindecombustibleTab, 'text');
            app.W76.ValueChangedFcn = createCallbackFcn(app, @W76ValueChanged, true);
            app.W8Label = uilabel(app.FraccindecombustibleTab);
            app.W8Label.Interpreter = 'latex';
            app.W8Label.Text = '$W_{8/7}$';
            app.W87 = uieditfield(app.FraccindecombustibleTab, 'text');
            app.W87.ValueChangedFcn = createCallbackFcn(app, @W87ValueChanged, true);
            app.M_ffEditFieldLabel = uilabel(app.FraccindecombustibleTab);
            app.M_ffEditFieldLabel.Interpreter = 'latex';
            app.M_ffEditFieldLabel.Text = '$M_{ff}$';
            app.m_ff = uieditfield(app.FraccindecombustibleTab, 'text');
            app.m_ff.Editable = 'off';
            app.W8Label_2 = uilabel(app.FraccindecombustibleTab);
            app.W8Label_2.Interpreter = 'latex';
            app.W8Label_2.Text = '$W_{9/8}$';
            app.W98 = uieditfield(app.FraccindecombustibleTab, 'text');
            app.W98.ValueChangedFcn = createCallbackFcn(app, @W98ValueChanged, true);
            app.TabGroup2 = uitabgroup(app.FraccindecombustibleTab);
            app.AscensoTab = uitab(app.TabGroup2);
            app.AscensoTab.Title = 'Ascenso';
            app.W4Label_2 = uilabel(app.AscensoTab);
            app.W4Label_2.Interpreter = 'latex';
            app.W4Label_2.Text = '$E$';
            app.E_cl = uieditfield(app.AscensoTab, 'text');
            app.E_cl.ValueChangedFcn = createCallbackFcn(app, @E_clValueChanged, true);
            app.E_cl.Tooltip = {'hr'};
            app.W4Label_3 = uilabel(app.AscensoTab);
            app.W4Label_3.Interpreter = 'latex';
            app.W4Label_3.Text = '$\frac{L}{D}$';
            app.LD_cl = uieditfield(app.AscensoTab, 'text');
            app.LD_cl.ValueChangedFcn = createCallbackFcn(app, @LD_clValueChanged, true);
            app.V_cl = uieditfield(app.AscensoTab, 'text');
            app.V_cl.ValueChangedFcn = createCallbackFcn(app, @V_clValueChanged, true);
            app.V_cl.Tooltip = {''};
            app.W4Label_4 = uilabel(app.AscensoTab);
            app.W4Label_4.Interpreter = 'latex';
            app.W4Label_4.Text = '$V$';
            app.W4Label_5 = uilabel(app.AscensoTab);
            app.W4Label_5.Interpreter = 'latex';
            app.W4Label_5.Text = '$c$';
            app.c_cl = uieditfield(app.AscensoTab, 'text');
            app.c_cl.ValueChangedFcn = createCallbackFcn(app, @c_clValueChanged, true);
            app.W4Label_7 = uilabel(app.AscensoTab);
            app.W4Label_7.Interpreter = 'latex';
            app.W4Label_7.Text = '$\eta_p$';
            app.n_p_cl = uieditfield(app.AscensoTab, 'text');
            app.n_p_cl.ValueChangedFcn = createCallbackFcn(app, @n_p_clValueChanged, true);
            app.Tabla22Button1 = uibutton(app.AscensoTab, 'push');
            app.Tabla22Button1.ButtonPushedFcn = createCallbackFcn(app, @Tabla22Button1Pushed, true);
            app.Tabla22Button1.Text = 'Tabla 2.2';
            app.CruceroTab = uitab(app.TabGroup2);
            app.CruceroTab.Title = 'Crucero';
            app.W4Label_8 = uilabel(app.CruceroTab);
            app.W4Label_8.Interpreter = 'latex';
            app.W4Label_8.Text = '$R$';
            app.R_cr = uieditfield(app.CruceroTab, 'text');
            app.R_cr.ValueChangedFcn = createCallbackFcn(app, @R_crValueChanged, true);
            app.R_cr.Tooltip = {'nm'};
            app.W4Label_9 = uilabel(app.CruceroTab);
            app.W4Label_9.Interpreter = 'latex';
            app.W4Label_9.Text = '$c$';
            app.c_cr = uieditfield(app.CruceroTab, 'text');
            app.c_cr.ValueChangedFcn = createCallbackFcn(app, @c_crValueChanged, true);
            app.W4Label_10 = uilabel(app.CruceroTab);
            app.W4Label_10.Interpreter = 'latex';
            app.W4Label_10.Text = '$\eta_p$';
            app.n_p_cr = uieditfield(app.CruceroTab, 'text');
            app.n_p_cr.ValueChangedFcn = createCallbackFcn(app, @n_p_crValueChanged, true);
            app.W4Label_11 = uilabel(app.CruceroTab);
            app.W4Label_11.Interpreter = 'latex';
            app.W4Label_11.Text = '$\frac{L}{D}$';
            app.LD_cr = uieditfield(app.CruceroTab, 'text');
            app.LD_cr.ValueChangedFcn = createCallbackFcn(app, @LD_crValueChanged, true);
            app.W4Label_12 = uilabel(app.CruceroTab);
            app.W4Label_12.Interpreter = 'latex';
            app.W4Label_12.Text = '$V$';
            app.V_cr = uieditfield(app.CruceroTab, 'text');
            app.V_cr.ValueChangedFcn = createCallbackFcn(app, @V_crValueChanged, true);
            app.V_cr.Tooltip = {''};
            app.Tabla22Button2 = uibutton(app.CruceroTab, 'push');
            app.Tabla22Button2.ButtonPushedFcn = createCallbackFcn(app, @Tabla22Button2Pushed, true);
            app.Tabla22Button2.Text = 'Tabla 2.2';
            app.LoiterTab = uitab(app.TabGroup2);
            app.LoiterTab.Title = 'Loiter';
            app.W4Label_13 = uilabel(app.LoiterTab);
            app.W4Label_13.Interpreter = 'latex';
            app.W4Label_13.Text = '$\frac{L}{D}$';
            app.LD_lo = uieditfield(app.LoiterTab, 'text');
            app.LD_lo.ValueChangedFcn = createCallbackFcn(app, @LD_loValueChanged, true);
            app.W4Label_14 = uilabel(app.LoiterTab);
            app.W4Label_14.Interpreter = 'latex';
            app.W4Label_14.Text = '$c$';
            app.c_lo = uieditfield(app.LoiterTab, 'text');
            app.c_lo.ValueChangedFcn = createCallbackFcn(app, @c_loValueChanged, true);
            app.W4Label_15 = uilabel(app.LoiterTab);
            app.W4Label_15.Interpreter = 'latex';
            app.W4Label_15.Text = '$\eta_p$';
            app.n_p_lo = uieditfield(app.LoiterTab, 'text');
            app.n_p_lo.ValueChangedFcn = createCallbackFcn(app, @n_p_loValueChanged, true);
            app.W4Label_16 = uilabel(app.LoiterTab);
            app.W4Label_16.Interpreter = 'latex';
            app.W4Label_16.Text = '$V$';
            app.V_lo = uieditfield(app.LoiterTab, 'text');
            app.V_lo.ValueChangedFcn = createCallbackFcn(app, @V_loValueChanged, true);
            app.V_lo.Tooltip = {'S'};
            app.W4Label_17 = uilabel(app.LoiterTab);
            app.W4Label_17.Interpreter = 'latex';
            app.W4Label_17.Text = '$E$';
            app.E_lo = uieditfield(app.LoiterTab, 'text');
            app.E_lo.ValueChangedFcn = createCallbackFcn(app, @E_loValueChanged, true);
            app.E_lo.Tooltip = {'hr'};
            app.Tabla22Button3 = uibutton(app.LoiterTab, 'push');
            app.Tabla22Button3.ButtonPushedFcn = createCallbackFcn(app, @Tabla22Button3Pushed, true);
            app.Tabla22Button3.Text = 'Tabla 2.2';
            app.PesosTab = uitab(app.TabGroup5);
            app.PesosTab.Title = 'Pesos';
            app.we_eq = uilabel(app.PesosTab);
            app.we_eq.Interpreter = 'latex';
            app.we_eq.Text = '$=W_{to}-W_F-W_{PL}-W_{tfo}-W_{crew}$';
            app.WPlotButton = uibutton(app.PesosTab, 'push');
            app.WPlotButton.ButtonPushedFcn = createCallbackFcn(app, @WPlotButtonPushed, true);
            app.WPlotButton.Text = 'Plot';
            app.eqerror = uilabel(app.PesosTab);
            app.eqerror.Interpreter = 'latex';
            app.eqerror.Text = '$=\frac{|W_E-W_{E,target}|}{W_{E,target}}\cdot 100$';
            app.W5Label_4 = uilabel(app.PesosTab);
            app.W5Label_4.Interpreter = 'latex';
            app.W5Label_4.Text = '$W_F$';
            app.Wf = uieditfield(app.PesosTab, 'text');
            app.Wf.Editable = 'off';
            app.W5Label_5 = uilabel(app.PesosTab);
            app.W5Label_5.Interpreter = 'latex';
            app.W5Label_5.Text = '$=W_{f,used}$';
            app.Wf_used = uieditfield(app.PesosTab, 'text');
            app.Wf_used.Editable = 'off';
            app.W5Label_6 = uilabel(app.PesosTab);
            app.W5Label_6.Interpreter = 'latex';
            app.W5Label_6.Text = '$+W_{f,res}$';
            app.Wf_res = uieditfield(app.PesosTab, 'text');
            app.Wf_res.ValueChangedFcn = createCallbackFcn(app, @Wf_resValueChanged, true);
            app.Wf_res.Editable = 'off';
            app.W5Label_7 = uilabel(app.PesosTab);
            app.W5Label_7.Interpreter = 'latex';
            app.W5Label_7.Text = '$W_{tfo}$';
            app.Wtfo = uieditfield(app.PesosTab, 'text');
            app.Wtfo.Editable = 'off';
            app.W5Label_8 = uilabel(app.PesosTab);
            app.W5Label_8.Interpreter = 'latex';
            app.W5Label_8.Text = '$W_{PL}$';
            app.Wpl = uieditfield(app.PesosTab, 'text');
            app.Wpl.ValueChangedFcn = createCallbackFcn(app, @WplValueChanged, true);
            app.W5Label_9 = uilabel(app.PesosTab);
            app.W5Label_9.Interpreter = 'latex';
            app.W5Label_9.Text = '$W_{crew}$';
            app.Wcrew = uieditfield(app.PesosTab, 'text');
            app.Wcrew.ValueChangedFcn = createCallbackFcn(app, @WcrewValueChanged, true);
            app.W5Label_10 = uilabel(app.PesosTab);
            app.W5Label_10.Interpreter = 'latex';
            app.W5Label_10.Text = '$W_E$';
            app.WE = uieditfield(app.PesosTab, 'text');
            app.WE.Editable = 'off';
            app.W5Label_11 = uilabel(app.PesosTab);
            app.W5Label_11.Interpreter = 'latex';
            app.W5Label_11.Text = '$W_{to}$';
            app.W_to = uieditfield(app.PesosTab, 'text');
            app.W_to.ValueChangedFcn = createCallbackFcn(app, @W_toValueChanged, true);
            app.W_to.Tooltip = {''};
            app.W5Label_12 = uilabel(app.PesosTab);
            app.W5Label_12.Interpreter = 'latex';
            app.W5Label_12.Text = '$Err$';
            app.WError = uieditfield(app.PesosTab, 'text');
            app.WError.Editable = 'off';
            app.W5Label_13 = uilabel(app.PesosTab);
            app.W5Label_13.Interpreter = 'latex';
            app.W5Label_13.Text = '$\rightarrow W_{E, target}$';
            app.WEtarget = uieditfield(app.PesosTab, 'text');
            app.WEtarget.Editable = 'off';
            app.WEtarget.Tooltip = {''};
            app.PuntodediseoTab = uitab(app.TabGroup3);
            app.PuntodediseoTab.Title = 'Punto de diseño';
            app.TabGroup4 = uitabgroup(app.PuntodediseoTab);
            app.StallTab = uitab(app.TabGroup4);
            app.StallTab.Title = 'Stall';
            app.Tabla31Button1 = uibutton(app.StallTab, 'push');
            app.Tabla31Button1.ButtonPushedFcn = createCallbackFcn(app, @Tabla31Button1Pushed, true);
            app.Tabla31Button1.Text = 'Tabla 3.1';
            app.stalltable = uitable(app.StallTab);
            app.stalltable.ColumnName = {'Vs'; 'h'; 'CL'; 'W/S'; 'plot'};
            app.stalltable.SelectionType = 'row';
            app.stalltable.SelectionChangedFcn = createCallbackFcn(app, @stalltableSelectionChanged, true);
            app.stalltable.Multiselect = 'off';
            app.stallPlotButton = uibutton(app.StallTab, 'push');
            app.stallPlotButton.ButtonPushedFcn = createCallbackFcn(app, @stallPlotButtonPushed, true);
            app.stallPlotButton.Text = 'Plot T/W vs W/S';
            app.stallplus = uibutton(app.StallTab, 'push');
            app.stallplus.ButtonPushedFcn = createCallbackFcn(app, @stallplusButtonPushed, true);
            app.stallplus.Text = '+';
            app.stallminus = uibutton(app.StallTab, 'push');
            app.stallminus.ButtonPushedFcn = createCallbackFcn(app, @stallminusButtonPushed, true);
            app.stallminus.Text = '-';
            app.stallplot = uicheckbox(app.StallTab);
            app.stallplot.ValueChangedFcn = createCallbackFcn(app, @stallplotValueChanged, true);
            app.stallplot.Text = 'Plot';
            app.stallplot.Value = true;
            app.stallplotenable = uicheckbox(app.StallTab);
            app.stallplotenable.ValueChangedFcn = createCallbackFcn(app, @stallplotenableValueChanged, true);
            app.stallplotenable.Text = 'Plot Stall Sizing';
            app.stallplotenable.Value = true;
            app.W5Label_19 = uilabel(app.StallTab);
            app.W5Label_19.Interpreter = 'latex';
            app.W5Label_19.Text = '$h$';
            app.stallheight = uieditfield(app.StallTab, 'text');
            app.stallheight.ValueChangedFcn = createCallbackFcn(app, @stallheightValueChanged, true);
            app.stallheight.Tooltip = {'ft'};
            app.stallheight.Value = '0';
            app.W5Label_20 = uilabel(app.StallTab);
            app.W5Label_20.Interpreter = 'latex';
            app.W5Label_20.Text = '$\rightarrow \rho$';
            app.stallrho = uieditfield(app.StallTab, 'text');
            app.stallrho.Editable = 'off';
            app.stallrho.Tooltip = {'slug/ft3'};
            app.W5Label_21 = uilabel(app.StallTab);
            app.W5Label_21.Interpreter = 'latex';
            app.W5Label_21.Text = {'$C_L'; '$'};
            app.stallcl = uieditfield(app.StallTab, 'text');
            app.stallcl.ValueChangedFcn = createCallbackFcn(app, @stallclValueChanged, true);
            app.stallcl.Tooltip = {''};
            app.stallcl.Value = '1.1';
            app.W5Label_22 = uilabel(app.StallTab);
            app.W5Label_22.Interpreter = 'latex';
            app.W5Label_22.Text = '$V_s$';
            app.stallvs = uieditfield(app.StallTab, 'text');
            app.stallvs.ValueChangedFcn = createCallbackFcn(app, @stallvsValueChanged, true);
            app.stallvs.Tooltip = {'ktas'};
            app.stallvs.Value = '0';
            app.TakeOffTab = uitab(app.TabGroup4);
            app.TakeOffTab.Title = 'TakeOff';
            app.totable = uitable(app.TakeOffTab);
            app.totable.ColumnName = {'Stofl'; 'h'; 'CLmax'; 'plot'};
            app.totable.SelectionType = 'row';
            app.totable.SelectionChangedFcn = createCallbackFcn(app, @totableSelectionChanged, true);
            app.totable.Multiselect = 'off';
            app.Tabla31Button2 = uibutton(app.TakeOffTab, 'push');
            app.Tabla31Button2.ButtonPushedFcn = createCallbackFcn(app, @Tabla31Button2Pushed, true);
            app.Tabla31Button2.Text = 'Tabla 3.1';
            app.toPlotButton = uibutton(app.TakeOffTab, 'push');
            app.toPlotButton.ButtonPushedFcn = createCallbackFcn(app, @toPlotButtonPushed, true);
            app.toPlotButton.Text = 'Plot T/W vs W/S';
            app.toplus = uibutton(app.TakeOffTab, 'push');
            app.toplus.ButtonPushedFcn = createCallbackFcn(app, @toplusButtonPushed, true);
            app.toplus.Text = '+';
            app.tominus = uibutton(app.TakeOffTab, 'push');
            app.tominus.ButtonPushedFcn = createCallbackFcn(app, @tominusButtonPushed, true);
            app.tominus.Text = '-';
            app.toplot = uicheckbox(app.TakeOffTab);
            app.toplot.ValueChangedFcn = createCallbackFcn(app, @toplotValueChanged, true);
            app.toplot.Text = 'Plot';
            app.toplot.Value = true;
            app.toplotenable = uicheckbox(app.TakeOffTab);
            app.toplotenable.ValueChangedFcn = createCallbackFcn(app, @toplotenableValueChanged, true);
            app.toplotenable.Text = 'Plot Take-Off Sizing';
            app.toplotenable.Value = true;
            app.W5Label_14 = uilabel(app.TakeOffTab);
            app.W5Label_14.Interpreter = 'latex';
            app.W5Label_14.Text = '$h$';
            app.toheight = uieditfield(app.TakeOffTab, 'text');
            app.toheight.ValueChangedFcn = createCallbackFcn(app, @toheightValueChanged, true);
            app.toheight.Tooltip = {'ft'};
            app.toheight.Value = '0';
            app.W5Label_15 = uilabel(app.TakeOffTab);
            app.W5Label_15.Interpreter = 'latex';
            app.W5Label_15.Text = '$\rightarrow TOP_{25}$';
            app.totop25 = uieditfield(app.TakeOffTab, 'text');
            app.totop25.Editable = 'off';
            app.totop25.Tooltip = {''};
            app.W5Label_16 = uilabel(app.TakeOffTab);
            app.W5Label_16.Interpreter = 'latex';
            app.W5Label_16.Text = '$\rightarrow \sigma$';
            app.tosigma = uieditfield(app.TakeOffTab, 'text');
            app.tosigma.Editable = 'off';
            app.tosigma.Tooltip = {''};
            app.W5Label_17 = uilabel(app.TakeOffTab);
            app.W5Label_17.Interpreter = 'latex';
            app.W5Label_17.Text = {'$C_{L_{max,TO'; '}}$'};
            app.toclmax = uieditfield(app.TakeOffTab, 'text');
            app.toclmax.ValueChangedFcn = createCallbackFcn(app, @toclmaxValueChanged, true);
            app.toclmax.Tooltip = {''};
            app.toclmax.Value = '1.1';
            app.W5Label_18 = uilabel(app.TakeOffTab);
            app.W5Label_18.Interpreter = 'latex';
            app.W5Label_18.Text = '$S_{TOFL}$';
            app.toStofl = uieditfield(app.TakeOffTab, 'text');
            app.toStofl.ValueChangedFcn = createCallbackFcn(app, @toStoflValueChanged, true);
            app.toStofl.Tooltip = {'ft'};
            app.toStofl.Value = '0';
            app.LandTab = uitab(app.TabGroup4);
            app.LandTab.Title = 'Land';
            app.lanplot = uicheckbox(app.LandTab);
            app.lanplot.ValueChangedFcn = createCallbackFcn(app, @lanplotValueChanged, true);
            app.lanplot.Text = 'Plot';
            app.lanplot.Value = true;
            app.Tabla31Button3 = uibutton(app.LandTab, 'push');
            app.Tabla31Button3.ButtonPushedFcn = createCallbackFcn(app, @Tabla31Button3Pushed, true);
            app.Tabla31Button3.Text = 'Tabla 3.1';
            app.lantable = uitable(app.LandTab);
            app.lantable.ColumnName = {'SL'; 'h'; 'CL'; 'Wl=Wto'; 'plot'};
            app.lantable.SelectionType = 'row';
            app.lantable.SelectionChangedFcn = createCallbackFcn(app, @lantableSelectionChanged, true);
            app.lantable.Multiselect = 'off';
            app.lanPlotButton = uibutton(app.LandTab, 'push');
            app.lanPlotButton.ButtonPushedFcn = createCallbackFcn(app, @lanPlotButtonPushed, true);
            app.lanPlotButton.Text = 'Plot T/W vs W/S';
            app.lanplus = uibutton(app.LandTab, 'push');
            app.lanplus.ButtonPushedFcn = createCallbackFcn(app, @lanplusButtonPushed, true);
            app.lanplus.Text = '+';
            app.lanminus = uibutton(app.LandTab, 'push');
            app.lanminus.ButtonPushedFcn = createCallbackFcn(app, @lanminusButtonPushed, true);
            app.lanminus.Text = '-';
            app.lanplotenable = uicheckbox(app.LandTab);
            app.lanplotenable.ValueChangedFcn = createCallbackFcn(app, @lanplotenableValueChanged, true);
            app.lanplotenable.Text = 'Plot Landing Sizing';
            app.lanplotenable.Value = true;
            app.W5Label_23 = uilabel(app.LandTab);
            app.W5Label_23.Interpreter = 'latex';
            app.W5Label_23.Text = '$h$';
            app.lanheight = uieditfield(app.LandTab, 'text');
            app.lanheight.ValueChangedFcn = createCallbackFcn(app, @lanheightValueChanged, true);
            app.lanheight.Tooltip = {'ft'};
            app.lanheight.Value = '0';
            app.W5Label_25 = uilabel(app.LandTab);
            app.W5Label_25.Interpreter = 'latex';
            app.W5Label_25.Text = '$\rightarrow \rho$';
            app.lanrho = uieditfield(app.LandTab, 'text');
            app.lanrho.Editable = 'off';
            app.lanrho.Tooltip = {'slug/ft3'};
            app.W5Label_26 = uilabel(app.LandTab);
            app.W5Label_26.Interpreter = 'latex';
            app.W5Label_26.Text = '$C_{L_{max,L}}$';
            app.lancl = uieditfield(app.LandTab, 'text');
            app.lancl.ValueChangedFcn = createCallbackFcn(app, @lanclValueChanged, true);
            app.lancl.Tooltip = {''};
            app.lancl.Value = '1.1';
            app.W5Label_27 = uilabel(app.LandTab);
            app.W5Label_27.Interpreter = 'latex';
            app.W5Label_27.Text = '$s_{L}$';
            app.lansl = uieditfield(app.LandTab, 'text');
            app.lansl.ValueChangedFcn = createCallbackFcn(app, @lanslValueChanged, true);
            app.lansl.Tooltip = {'ft'};
            app.lansl.Value = '0';
            app.W5Label_28 = uilabel(app.LandTab);
            app.W5Label_28.Interpreter = 'latex';
            app.W5Label_28.Text = '$W_{L}=W_{TO}\cdot$';
            app.lanrel = uieditfield(app.LandTab, 'text');
            app.lanrel.ValueChangedFcn = createCallbackFcn(app, @lanrelValueChanged, true);
            app.lanrel.Tooltip = {''};
            app.lanrel.Value = '0';
            app.DragTab = uitab(app.TabGroup4);
            app.DragTab.Title = 'Drag';
            app.draglabel2 = uilabel(app.DragTab);
            app.draglabel2.Interpreter = 'latex';
            app.draglabel2.Text = 'Take-Off, gear up';
            app.draglabel3 = uilabel(app.DragTab);
            app.draglabel3.Interpreter = 'latex';
            app.draglabel3.Text = 'Take-Off, gear down';
            app.draglabel4 = uilabel(app.DragTab);
            app.draglabel4.Interpreter = 'latex';
            app.draglabel4.Text = 'Landing, gear up';
            app.draglabel5 = uilabel(app.DragTab);
            app.draglabel5.Interpreter = 'latex';
            app.draglabel5.Text = 'Landing, gear down';
            app.draglabel12 = uilabel(app.DragTab);
            app.draglabel12.Interpreter = 'latex';
            app.draglabel12.Text = '${C_L}^2$';
            app.draglabel22 = uilabel(app.DragTab);
            app.draglabel22.Interpreter = 'latex';
            app.draglabel22.Text = '${C_L}^2$';
            app.draglabel32 = uilabel(app.DragTab);
            app.draglabel32.Interpreter = 'latex';
            app.draglabel32.Text = '${C_L}^2$';
            app.draglabel42 = uilabel(app.DragTab);
            app.draglabel42.Interpreter = 'latex';
            app.draglabel42.Text = '${C_L}^2$';
            app.draglabel52 = uilabel(app.DragTab);
            app.draglabel52.Interpreter = 'latex';
            app.draglabel52.Text = '${C_L}^2$';
            app.C_DLabel = uilabel(app.DragTab);
            app.C_DLabel.Interpreter = 'latex';
            app.C_DLabel.Text = '$C_D =$';
            app.dragv11 = uieditfield(app.DragTab, 'text');
            app.dragv11.Editable = 'off';
            app.Label_2 = uilabel(app.DragTab);
            app.Label_2.Interpreter = 'latex';
            app.Label_2.Text = '$+$';
            app.dragv12 = uieditfield(app.DragTab, 'text');
            app.dragv12.Editable = 'off';
            app.C_DLabel_2 = uilabel(app.DragTab);
            app.C_DLabel_2.Interpreter = 'latex';
            app.C_DLabel_2.Text = '$C_D =$';
            app.dragv21 = uieditfield(app.DragTab, 'text');
            app.dragv21.Editable = 'off';
            app.Label_3 = uilabel(app.DragTab);
            app.Label_3.Interpreter = 'latex';
            app.Label_3.Text = '$+$';
            app.dragv22 = uieditfield(app.DragTab, 'text');
            app.dragv22.Editable = 'off';
            app.Label_4 = uilabel(app.DragTab);
            app.Label_4.Interpreter = 'latex';
            app.Label_4.Text = '$+$';
            app.dragv32 = uieditfield(app.DragTab, 'text');
            app.dragv32.Editable = 'off';
            app.C_DLabel_3 = uilabel(app.DragTab);
            app.C_DLabel_3.Interpreter = 'latex';
            app.C_DLabel_3.Text = '$C_D =$';
            app.dragv31 = uieditfield(app.DragTab, 'text');
            app.dragv31.Editable = 'off';
            app.Label_5 = uilabel(app.DragTab);
            app.Label_5.Interpreter = 'latex';
            app.Label_5.Text = '$+$';
            app.dragv42 = uieditfield(app.DragTab, 'text');
            app.dragv42.Editable = 'off';
            app.C_DLabel_4 = uilabel(app.DragTab);
            app.C_DLabel_4.Interpreter = 'latex';
            app.C_DLabel_4.Text = '$C_D =$';
            app.dragv41 = uieditfield(app.DragTab, 'text');
            app.dragv41.Editable = 'off';
            app.Label_6 = uilabel(app.DragTab);
            app.Label_6.Interpreter = 'latex';
            app.Label_6.Text = '$+$';
            app.dragv52 = uieditfield(app.DragTab, 'text');
            app.dragv52.Editable = 'off';
            app.C_DLabel_5 = uilabel(app.DragTab);
            app.C_DLabel_5.Interpreter = 'latex';
            app.C_DLabel_5.Text = '$C_D =$';
            app.dragv51 = uieditfield(app.DragTab, 'text');
            app.dragv51.Editable = 'off';
            app.draglabel1 = uilabel(app.DragTab);
            app.draglabel1.Interpreter = 'latex';
            app.draglabel1.Text = 'Low Speed, clean';
            app.dragtab = uitabgroup(app.DragTab);
            app.dragtab1 = uitab(app.dragtab);
            app.dragtab1.Title = 'Clean';
            app.W5Label_35 = uilabel(app.dragtab1);
            app.W5Label_35.Interpreter = 'latex';
            app.W5Label_35.Text = '$W_{to} \rightarrow S_{wet}$';
            app.dragswet = uieditfield(app.dragtab1, 'text');
            app.dragswet.ValueChangedFcn = createCallbackFcn(app, @dragswetValueChanged, true);
            app.dragswet.Tooltip = {'ft2'};
            app.c_fDropDownLabel = uilabel(app.dragtab1);
            app.c_fDropDownLabel.Interpreter = 'latex';
            app.c_fDropDownLabel.Text = '$c_f$';
            app.dragcf = uidropdown(app.dragtab1);
            app.dragcf.Items = {'0.002', '0.003', '0.004', '0.005', '0.006', '0.007', '0.008', '0.009'};
            app.dragcf.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.dragcf.ValueChangedFcn = createCallbackFcn(app, @dragcfValueChanged, true);
            app.dragcf.Value = '1';
            app.W5Label_36 = uilabel(app.dragtab1);
            app.W5Label_36.Interpreter = 'latex';
            app.W5Label_36.Text = '$f$';
            app.dragf = uieditfield(app.dragtab1, 'text');
            app.dragf.Editable = 'off';
            app.dragf.Tooltip = {'ft2'};
            app.W5Label_37 = uilabel(app.dragtab1);
            app.W5Label_37.Interpreter = 'latex';
            app.W5Label_37.Text = '$S$';
            app.drags = uieditfield(app.dragtab1, 'text');
            app.drags.ValueChangedFcn = createCallbackFcn(app, @dragsValueChanged, true);
            app.drags.Tooltip = {'ft2'};
            app.W5Label_38 = uilabel(app.dragtab1);
            app.W5Label_38.Interpreter = 'latex';
            app.W5Label_38.Text = '$\rightarrow C_{D_0}$';
            app.dragcd0 = uieditfield(app.dragtab1, 'text');
            app.dragcd0.Editable = 'off';
            app.dragcd0.Tooltip = {''};
            app.W5Label_39 = uilabel(app.dragtab1);
            app.W5Label_39.Interpreter = 'latex';
            app.W5Label_39.Text = '$A$';
            app.draga = uieditfield(app.dragtab1, 'text');
            app.draga.ValueChangedFcn = createCallbackFcn(app, @dragaValueChanged, true);
            app.draga.Tooltip = {''};
            app.dragrightarrowLabel = uilabel(app.dragtab1);
            app.dragrightarrowLabel.Interpreter = 'latex';
            app.dragrightarrowLabel.Text = '$\rightarrow$';
            app.W5Label_40 = uilabel(app.dragtab1);
            app.W5Label_40.Interpreter = 'latex';
            app.W5Label_40.Text = '$e$';
            app.drage = uieditfield(app.dragtab1, 'text');
            app.drage.ValueChangedFcn = createCallbackFcn(app, @drageValueChanged, true);
            app.drage.Tooltip = {''};
            app.Tabla36Button1 = uibutton(app.dragtab1, 'push');
            app.Tabla36Button1.ButtonPushedFcn = createCallbackFcn(app, @Tabla36Button1Pushed, true);
            app.Tabla36Button1.Text = 'Tabla 3.6';
            app.W5Label_41 = uilabel(app.dragtab1);
            app.W5Label_41.Interpreter = 'latex';
            app.W5Label_41.Text = '$\frac{W}{S}_{to}$';
            app.dragws = uieditfield(app.dragtab1, 'text');
            app.dragws.Editable = 'off';
            app.dragws.Tooltip = {''};
            app.dragtab2 = uitab(app.dragtab);
            app.dragtab2.Title = 'W/ Flaps & Landing Gear';
            app.dragtable = uitable(app.dragtab2);
            app.dragtable.ColumnName = {'Delta CD0'; 'e'};
            app.dragtable.RowName = {'Take Off Flaps'; 'Landing Flaps'; 'Landing Gear'};
            app.dragtable.ColumnEditable = true;
            app.dragtable.CellEditCallback = createCallbackFcn(app, @dragtableCellEdit, true);
            app.Tabla36Button2 = uibutton(app.dragtab2, 'push');
            app.Tabla36Button2.ButtonPushedFcn = createCallbackFcn(app, @Tabla36Button2Pushed, true);
            app.Tabla36Button2.Text = 'Tabla 3.6';
            app.ClimbTab = uitab(app.TabGroup4);
            app.ClimbTab.Title = 'Climb';
            app.climbplot = uicheckbox(app.ClimbTab);
            app.climbplot.ValueChangedFcn = createCallbackFcn(app, @climbplotValueChanged, true);
            app.climbplot.Text = 'Plot';
            app.climbplot.Value = true;
            app.climbplus = uibutton(app.ClimbTab, 'push');
            app.climbplus.ButtonPushedFcn = createCallbackFcn(app, @climbplusButtonPushed, true);
            app.climbplus.Text = '+';
            app.climbminus = uibutton(app.ClimbTab, 'push');
            app.climbminus.ButtonPushedFcn = createCallbackFcn(app, @climbminusButtonPushed, true);
            app.climbminus.Text = '-';
            app.climblabel1 = uilabel(app.ClimbTab);
            app.climblabel1.Tooltip = {'Take off climb'; 'gear up, take-off flaps'};
            app.climblabel1.Text = '(OEI) CGR>0.012';
            app.climblabel2 = uilabel(app.ClimbTab);
            app.climblabel2.Tooltip = {'Take off climb'; 'gear down, take-off flaps'};
            app.climblabel2.Text = '(OEI) CGR>0';
            app.climblabel3 = uilabel(app.ClimbTab);
            app.climblabel3.Tooltip = {'Take off climb'; 'gear up, take-off flaps'};
            app.climblabel3.Text = '(OEI) CGR>0.024';
            app.climbvs1 = uieditfield(app.ClimbTab, 'text');
            app.climbvs1.Editable = 'off';
            app.climbvs1.Value = '1.2';
            app.climbvs2 = uieditfield(app.ClimbTab, 'text');
            app.climbvs2.ValueChangedFcn = createCallbackFcn(app, @climbvs2ValueChanged, true);
            app.climbvs2.Value = '0';
            app.climbvs3 = uieditfield(app.ClimbTab, 'text');
            app.climbvs3.Editable = 'off';
            app.climbvs3.Value = '1.2';
            app.climbvslabel = uilabel(app.ClimbTab);
            app.climbvslabel.Interpreter = 'latex';
            app.climbvslabel.Text = '$V_s \cdot$';
            app.climbtable = uitable(app.ClimbTab);
            app.climbtable.ColumnName = {'cl'; 'cd'; 'LD'; 'TW'; 'plot'};
            app.climbtable.RowName = {};
            app.climbtable.SelectionType = 'row';
            app.climbtable.ColumnEditable = [false false false false true];
            app.climbtable.CellEditCallback = createCallbackFcn(app, @climbtableCellEdit, true);
            app.climbtable.Multiselect = 'off';
            app.climbvs4 = uieditfield(app.ClimbTab, 'text');
            app.climbvs4.Editable = 'off';
            app.climbvs4.Value = '1.25';
            app.climbvs5 = uieditfield(app.ClimbTab, 'text');
            app.climbvs5.Editable = 'off';
            app.climbvs5.Value = '1.3';
            app.climbvs6 = uieditfield(app.ClimbTab, 'text');
            app.climbvs6.Editable = 'off';
            app.climbvs6.Value = '1.5';
            app.climblabel4 = uilabel(app.ClimbTab);
            app.climblabel4.Tooltip = {'En route'; 'gear up, flaps up'};
            app.climblabel4.Text = '(OEI) CGR>0.012';
            app.climbenroutelabel = uilabel(app.ClimbTab);
            app.climbenroutelabel.Text = 'En route';
            app.climblandinglabel = uilabel(app.ClimbTab);
            app.climblandinglabel.Text = 'Landing';
            app.climblabel5 = uilabel(app.ClimbTab);
            app.climblabel5.Tooltip = {'Landing climb'; 'gear down, landing flaps'};
            app.climblabel5.Text = '(AEO) CGR>0.032';
            app.climblabel6 = uilabel(app.ClimbTab);
            app.climblabel6.Tooltip = {'Landing climb'; 'gear down, approach flaps'};
            app.climblabel6.Text = '(OEI) CGR>0.021';
            app.climbPlotButton = uibutton(app.ClimbTab, 'push');
            app.climbPlotButton.ButtonPushedFcn = createCallbackFcn(app, @climbPlotButtonPushed, true);
            app.climbPlotButton.Text = 'Plot T/W vs W/S';
            app.climbplotenable = uicheckbox(app.ClimbTab);
            app.climbplotenable.ValueChangedFcn = createCallbackFcn(app, @climbplotenableValueChanged, true);
            app.climbplotenable.Text = 'Plot Climb Sizing';
            app.climbplotenable.Value = true;
            app.climbapproachlabel = uilabel(app.ClimbTab);
            app.climbapproachlabel.Text = 'Approach';
            app.ConfiguracinDropDownLabel = uilabel(app.ClimbTab);
            app.ConfiguracinDropDownLabel.Text = 'Configuración';
            app.climbselect = uidropdown(app.ClimbTab);
            app.climbselect.Items = {'1'};
            app.climbselect.ValueChangedFcn = createCallbackFcn(app, @climbselectValueChanged, true);
            app.climbselect.Value = '1';
            app.C_L_tomaxLabel = uilabel(app.ClimbTab);
            app.C_L_tomaxLabel.Interpreter = 'latex';
            app.C_L_tomaxLabel.Text = '$C_{L_{to,max}}$';
            app.climbtocl = uieditfield(app.ClimbTab, 'text');
            app.climbtocl.ValueChangedFcn = createCallbackFcn(app, @climbtoclValueChanged, true);
            app.climbtocl.Value = '0';
            app.C_L_tomaxLabel_2 = uilabel(app.ClimbTab);
            app.C_L_tomaxLabel_2.Interpreter = 'latex';
            app.C_L_tomaxLabel_2.Text = '$C_{L_{max}}$';
            app.climbercl = uieditfield(app.ClimbTab, 'text');
            app.climbercl.ValueChangedFcn = createCallbackFcn(app, @climberclValueChanged, true);
            app.climbercl.Value = '0';
            app.C_L_tomaxLabel_3 = uilabel(app.ClimbTab);
            app.C_L_tomaxLabel_3.Interpreter = 'latex';
            app.C_L_tomaxLabel_3.Text = '$C_{L_{L,max}}$';
            app.climblancl = uieditfield(app.ClimbTab, 'text');
            app.climblancl.ValueChangedFcn = createCallbackFcn(app, @climblanclValueChanged, true);
            app.climblancl.Value = '0';
            app.C_L_tomaxLabel_4 = uilabel(app.ClimbTab);
            app.C_L_tomaxLabel_4.Interpreter = 'latex';
            app.C_L_tomaxLabel_4.Text = '$W_L=W_{to}$';
            app.climbwlwto = uieditfield(app.ClimbTab, 'text');
            app.climbwlwto.ValueChangedFcn = createCallbackFcn(app, @climbwlwtoValueChanged, true);
            app.climbwlwto.Value = '0';
            app.C_L_tomaxLabel_5 = uilabel(app.ClimbTab);
            app.C_L_tomaxLabel_5.Interpreter = 'latex';
            app.C_L_tomaxLabel_5.Text = '$C_{L_{A,max}}$';
            app.climbappcl = uieditfield(app.ClimbTab, 'text');
            app.climbappcl.ValueChangedFcn = createCallbackFcn(app, @climbappclValueChanged, true);
            app.climbappcl.Value = '0';
            app.climbtakeofflabel = uilabel(app.ClimbTab);
            app.climbtakeofflabel.Text = 'Take-Off';
            app.climbplotenable2 = uicheckbox(app.ClimbTab);
            app.climbplotenable2.ValueChangedFcn = createCallbackFcn(app, @climbplotenable2ValueChanged, true);
            app.climbplotenable2.Text = 'Plot solo TW+';
            app.climbplotenable2.Value = true;
            app.CruiseTab = uitab(app.TabGroup4);
            app.CruiseTab.Title = 'Cruise';
            app.cruiselabel1 = uilabel(app.CruiseTab);
            app.cruiselabel1.Interpreter = 'latex';
            app.cruiselabel1.Text = '$\cdot \frac{W}{S}$';
            app.cruiselabel2 = uilabel(app.CruiseTab);
            app.cruiselabel2.Interpreter = 'latex';
            app.cruiselabel2.Text = '$=\frac{T}{W}_{to}$';
            app.cruisetable = uitable(app.CruiseTab);
            app.cruisetable.ColumnName = {'M'; 'h'; 'dcd0'; 'k'; 't'; 'plot'};
            app.cruisetable.RowName = {};
            app.cruisetable.SelectionType = 'row';
            app.cruisetable.SelectionChangedFcn = createCallbackFcn(app, @cruisetableSelectionChanged, true);
            app.cruisetable.Multiselect = 'off';
            app.cruisePlotButton = uibutton(app.CruiseTab, 'push');
            app.cruisePlotButton.ButtonPushedFcn = createCallbackFcn(app, @cruisePlotButtonPushed, true);
            app.cruisePlotButton.Text = 'Plot T/W vs W/S';
            app.cruiseplus = uibutton(app.CruiseTab, 'push');
            app.cruiseplus.ButtonPushedFcn = createCallbackFcn(app, @cruiseplusButtonPushed, true);
            app.cruiseplus.Text = '+';
            app.cruiseminus = uibutton(app.CruiseTab, 'push');
            app.cruiseminus.ButtonPushedFcn = createCallbackFcn(app, @cruiseminusButtonPushed, true);
            app.cruiseminus.Text = '-';
            app.cruiseplotenable = uicheckbox(app.CruiseTab);
            app.cruiseplotenable.ValueChangedFcn = createCallbackFcn(app, @cruiseplotenableValueChanged, true);
            app.cruiseplotenable.Text = 'Plot Cruise Sizing';
            app.cruiseplotenable.Value = true;
            app.cruiseplot = uicheckbox(app.CruiseTab);
            app.cruiseplot.ValueChangedFcn = createCallbackFcn(app, @cruiseplotValueChanged, true);
            app.cruiseplot.Text = 'Plot';
            app.cruiseplot.Value = true;
            app.MEditFieldLabel = uilabel(app.CruiseTab);
            app.MEditFieldLabel.Interpreter = 'latex';
            app.MEditFieldLabel.Text = 'M';
            app.cruisem = uieditfield(app.CruiseTab, 'text');
            app.cruisem.ValueChangedFcn = createCallbackFcn(app, @cruisemValueChanged, true);
            app.hLabel = uilabel(app.CruiseTab);
            app.hLabel.Interpreter = 'latex';
            app.hLabel.Text = 'h';
            app.cruiseh = uieditfield(app.CruiseTab, 'text');
            app.cruiseh.ValueChangedFcn = createCallbackFcn(app, @cruisehValueChanged, true);
            app.cruiseh.Tooltip = {'ft'};
            app.rightarrowqEditFieldLabel = uilabel(app.CruiseTab);
            app.rightarrowqEditFieldLabel.Interpreter = 'latex';
            app.rightarrowqEditFieldLabel.Text = '$\rightarrow q$';
            app.cruiseq = uieditfield(app.CruiseTab, 'text');
            app.cruiseq.Editable = 'off';
            app.C_D0EditFieldLabel = uilabel(app.CruiseTab);
            app.C_D0EditFieldLabel.Interpreter = 'latex';
            app.C_D0EditFieldLabel.Text = '$C_{D,0}$';
            app.cruisecd01 = uieditfield(app.CruiseTab, 'text');
            app.cruisecd01.Editable = 'off';
            app.DeltaC_D0EditFieldLabel = uilabel(app.CruiseTab);
            app.DeltaC_D0EditFieldLabel.Interpreter = 'latex';
            app.DeltaC_D0EditFieldLabel.Text = '$\Delta C_{D,0}$';
            app.cruisedcd0 = uieditfield(app.CruiseTab, 'text');
            app.cruisedcd0.ValueChangedFcn = createCallbackFcn(app, @cruisedcd0ValueChanged, true);
            app.rightarrowC_D0EditFieldLabel = uilabel(app.CruiseTab);
            app.rightarrowC_D0EditFieldLabel.Interpreter = 'latex';
            app.rightarrowC_D0EditFieldLabel.Text = '$\rightarrow C_{D,0}$';
            app.cruisecd02 = uieditfield(app.CruiseTab, 'text');
            app.cruisecd02.Editable = 'off';
            app.fracTW_reqLabel = uilabel(app.CruiseTab);
            app.fracTW_reqLabel.Interpreter = 'latex';
            app.fracTW_reqLabel.Text = '$\frac{T}{W}_{req}=$';
            app.cruisev1 = uieditfield(app.CruiseTab, 'text');
            app.cruisev1.Editable = 'off';
            app.Label_7 = uilabel(app.CruiseTab);
            app.Label_7.Interpreter = 'latex';
            app.Label_7.Text = '$\cdot {\left(\frac{W}{S}\right)}^{-1}+$';
            app.cruisev2 = uieditfield(app.CruiseTab, 'text');
            app.cruisev2.Editable = 'off';
            app.fracTW_reqcdotLabel = uilabel(app.CruiseTab);
            app.fracTW_reqcdotLabel.Interpreter = 'latex';
            app.fracTW_reqcdotLabel.Text = '$\frac{T}{W}_{req}\rightarrow x$';
            app.cruisek = uieditfield(app.CruiseTab, 'text');
            app.cruisek.ValueChangedFcn = createCallbackFcn(app, @cruisekValueChanged, true);
            app.fracTW_reqcdotLabel_2 = uilabel(app.CruiseTab);
            app.fracTW_reqcdotLabel_2.Interpreter = 'latex';
            app.fracTW_reqcdotLabel_2.Text = '$=\frac{T}{W}_{static}\rightarrow \div$';
            app.cruiset = uieditfield(app.CruiseTab, 'text');
            app.cruiset.ValueChangedFcn = createCallbackFcn(app, @cruisetValueChanged, true);
            app.PTab = uitab(app.TabGroup4);
            app.PTab.Title = 'P';
            app.AlasTab = uitab(app.TabGroup3);
            app.AlasTab.Title = 'Alas';
            app.TabGroup6 = uitabgroup(app.AlasTab);
            app.CuerdasyposicinflapsTab = uitab(app.TabGroup6);
            app.CuerdasyposicinflapsTab.Title = 'Cuerdas y posición flaps';
            app.wingPlot = uiaxes(app.CuerdasyposicinflapsTab);
            xlabel(app.wingPlot, 'X')
            ylabel(app.wingPlot, 'Y')
            zlabel(app.wingPlot, 'Z')
            app.SLabel = uilabel(app.CuerdasyposicinflapsTab);
            app.SLabel.Interpreter = 'latex';
            app.SLabel.Text = '$S$';
            app.wingS = uieditfield(app.CuerdasyposicinflapsTab, 'numeric');
            app.wingS.Editable = 'off';
            app.wingS.Tooltip = {'Superficie alar [ft2]'};
            app.SLabel_2 = uilabel(app.CuerdasyposicinflapsTab);
            app.SLabel_2.Interpreter = 'latex';
            app.SLabel_2.Text = '$A$';
            app.wingA = uieditfield(app.CuerdasyposicinflapsTab, 'numeric');
            app.wingA.Editable = 'off';
            app.wingA.Tooltip = {'Razón de aspecto'};
            app.SLabel_3 = uilabel(app.CuerdasyposicinflapsTab);
            app.SLabel_3.Interpreter = 'latex';
            app.SLabel_3.Text = '$b$';
            app.wingb = uieditfield(app.CuerdasyposicinflapsTab, 'numeric');
            app.wingb.Editable = 'off';
            app.wingb.Tooltip = {'Envergadura [ft]'};
            app.SLabel_4 = uilabel(app.CuerdasyposicinflapsTab);
            app.SLabel_4.Interpreter = 'latex';
            app.SLabel_4.Text = '$\Lambda$';
            app.wingSweep = uieditfield(app.CuerdasyposicinflapsTab, 'numeric');
            app.wingSweep.Limits = [0 89];
            app.wingSweep.ValueChangedFcn = createCallbackFcn(app, @wingSweepValueChanged, true);
            app.wingSweep.Tooltip = {'Angulo de barrido [Grados] '};
            app.wingSweep.Value = 35;
            app.SLabel_5 = uilabel(app.CuerdasyposicinflapsTab);
            app.SLabel_5.Interpreter = 'latex';
            app.SLabel_5.Text = '$c_{root}$';
            app.wingCroot = uieditfield(app.CuerdasyposicinflapsTab, 'numeric');
            app.wingCroot.Limits = [0 Inf];
            app.wingCroot.Editable = 'off';
            app.wingCroot.Tooltip = {'Cuerda raíz [ft]'};
            app.SLabel_6 = uilabel(app.CuerdasyposicinflapsTab);
            app.SLabel_6.Interpreter = 'latex';
            app.SLabel_6.Text = '$c_{tip}$';
            app.wingCtip = uieditfield(app.CuerdasyposicinflapsTab, 'numeric');
            app.wingCtip.Limits = [0 Inf];
            app.wingCtip.Editable = 'off';
            app.wingCtip.Tooltip = {'Cuerda punta [ft]'};
            app.SLabel_7 = uilabel(app.CuerdasyposicinflapsTab);
            app.SLabel_7.Interpreter = 'latex';
            app.SLabel_7.Text = '$\lambda$';
            app.wingTaper = uieditfield(app.CuerdasyposicinflapsTab, 'numeric');
            app.wingTaper.Limits = [0 1];
            app.wingTaper.ValueChangedFcn = createCallbackFcn(app, @wingTaperValueChanged, true);
            app.wingTaper.Tooltip = {'Razón de ahusamiento'};
            app.wingTaper.Value = 0.3;
            app.x_1Label = uilabel(app.CuerdasyposicinflapsTab);
            app.x_1Label.Interpreter = 'latex';
            app.x_1Label.Text = '$x_1$';
            app.x_1Label_2 = uilabel(app.CuerdasyposicinflapsTab);
            app.x_1Label_2.Interpreter = 'latex';
            app.x_1Label_2.Text = '$x_2$';
            app.S_wfLabel = uilabel(app.CuerdasyposicinflapsTab);
            app.S_wfLabel.Interpreter = 'latex';
            app.S_wfLabel.Tooltip = {'Superficie flap [ft2]'};
            app.S_wfLabel.Text = '$S_{wf}$';
            app.wingSwf = uieditfield(app.CuerdasyposicinflapsTab, 'numeric');
            app.wingSwf.Editable = 'off';
            app.wingx2 = uieditfield(app.CuerdasyposicinflapsTab, 'numeric');
            app.wingx2.Limits = [0 1];
            app.wingx2.ValueChangedFcn = createCallbackFcn(app, @wingx2ValueChanged, true);
            app.wingx2.Value = 0.9;
            app.wingx1 = uieditfield(app.CuerdasyposicinflapsTab, 'numeric');
            app.wingx1.Limits = [0 1];
            app.wingx1.ValueChangedFcn = createCallbackFcn(app, @wingx1ValueChanged, true);
            app.wingx1.Value = 0.1;
            app.FlapsTab = uitab(app.TabGroup6);
            app.FlapsTab.Title = 'Flaps';
            app.C_LmaxLabel = uilabel(app.FlapsTab);
            app.C_LmaxLabel.Interpreter = 'latex';
            app.C_LmaxLabel.Text = '$C_{L max}$';
            app.liftreqCLmax = uieditfield(app.FlapsTab, 'numeric');
            app.liftreqCLmax.ValueDisplayFormat = '%11.6g';
            app.liftreqCLmax.ValueChangedFcn = createCallbackFcn(app, @liftreqCLmaxValueChanged, true);
            app.liftreqCLmax.Value = 1.59866;
            app.C_LmaxLabel_2 = uilabel(app.FlapsTab);
            app.C_LmaxLabel_2.Interpreter = 'latex';
            app.C_LmaxLabel_2.Text = '$C_{L max,TO}$';
            app.liftreqCLmaxTO = uieditfield(app.FlapsTab, 'numeric');
            app.liftreqCLmaxTO.ValueDisplayFormat = '%11.6g';
            app.liftreqCLmaxTO.Editable = 'off';
            app.C_LmaxLabel_3 = uilabel(app.FlapsTab);
            app.C_LmaxLabel_3.Interpreter = 'latex';
            app.C_LmaxLabel_3.Text = '$C_{L max,L}$';
            app.liftreqCLmaxL = uieditfield(app.FlapsTab, 'numeric');
            app.liftreqCLmaxL.ValueDisplayFormat = '%11.6g';
            app.liftreqCLmaxL.Editable = 'off';
            app.C_LmaxLabel_4 = uilabel(app.FlapsTab);
            app.C_LmaxLabel_4.Interpreter = 'latex';
            app.C_LmaxLabel_4.Text = '$\Delta C_{L max,TO}$';
            app.liftreqDeltaCLmaxTO = uieditfield(app.FlapsTab, 'numeric');
            app.liftreqDeltaCLmaxTO.ValueDisplayFormat = '%11.6g';
            app.liftreqDeltaCLmaxTO.Editable = 'off';
            app.C_LmaxLabel_5 = uilabel(app.FlapsTab);
            app.C_LmaxLabel_5.Interpreter = 'latex';
            app.C_LmaxLabel_5.Text = '$\Delta C_{L max,L}$';
            app.liftreqDeltaCLmaxL = uieditfield(app.FlapsTab, 'numeric');
            app.liftreqDeltaCLmaxL.ValueDisplayFormat = '%11.6g';
            app.liftreqDeltaCLmaxL.Editable = 'off';
            app.maxTOLmaxcdot105Label = uilabel(app.FlapsTab);
            app.maxTOLmaxcdot105Label.Interpreter = 'latex';
            app.maxTOLmaxcdot105Label.Text = '$(max,TO/L - max) \cdot 1.05$';
            app.cdotfracSS_wfK_LambdaLabel = uilabel(app.FlapsTab);
            app.cdotfracSS_wfK_LambdaLabel.Interpreter = 'latex';
            app.cdotfracSS_wfK_LambdaLabel.Text = '$\cdot \frac{S/S_{wf}}{K_\Lambda}=$';
            app.C_LmaxLabel_6 = uilabel(app.FlapsTab);
            app.C_LmaxLabel_6.Interpreter = 'latex';
            app.C_LmaxLabel_6.Text = '$\Delta C_{l max L}$';
            app.liftreqDeltaClmaxL = uieditfield(app.FlapsTab, 'numeric');
            app.liftreqDeltaClmaxL.ValueDisplayFormat = '%11.6g';
            app.liftreqDeltaClmaxL.Editable = 'off';
            app.C_LmaxLabel_7 = uilabel(app.FlapsTab);
            app.C_LmaxLabel_7.Interpreter = 'latex';
            app.C_LmaxLabel_7.Text = '$\Delta C_{l max TO}$';
            app.liftreqDeltaClmaxTO = uieditfield(app.FlapsTab, 'numeric');
            app.liftreqDeltaClmaxTO.ValueDisplayFormat = '%11.6g';
            app.liftreqDeltaClmaxTO.Editable = 'off';
            app.Label_9 = uilabel(app.FlapsTab);
            app.Label_9.Interpreter = 'latex';
            app.Label_9.Text = '$K_{\Lambda}$';
            app.liftKsweep = uieditfield(app.FlapsTab, 'numeric');
            app.liftKsweep.Editable = 'off';
            app.liftKsweep.Tooltip = {'Compensación angulo de barrido'};
            app.cfcDropDownLabel = uilabel(app.FlapsTab);
            app.cfcDropDownLabel.Text = 'cf/c';
            app.cfcDropDown = uidropdown(app.FlapsTab);
            app.cfcDropDown.Items = {'0.1', '0.2', '0.25', '0.3', '0.4'};
            app.cfcDropDown.ValueChangedFcn = createCallbackFcn(app, @cfcDropDownValueChanged, true);
            app.cfcDropDown.Value = '0.3';
            app.Label_10 = uilabel(app.FlapsTab);
            app.Label_10.Interpreter = 'latex';
            app.Label_10.Text = '$K$';
            app.liftK = uieditfield(app.FlapsTab, 'numeric');
            app.liftK.ValueChangedFcn = createCallbackFcn(app, @liftKValueChanged, true);
            app.liftK.Editable = 'off';
            app.liftK.Tooltip = {'Compensación profundidad'};
            app.C_LmaxLabel_8 = uilabel(app.FlapsTab);
            app.C_LmaxLabel_8.Interpreter = 'latex';
            app.C_LmaxLabel_8.Text = '$\Delta C_{l,0 L}$';
            app.liftreqDeltaCl0L = uieditfield(app.FlapsTab, 'numeric');
            app.liftreqDeltaCl0L.ValueDisplayFormat = '%11.6g';
            app.liftreqDeltaCl0L.Editable = 'off';
            app.C_LmaxLabel_9 = uilabel(app.FlapsTab);
            app.C_LmaxLabel_9.Interpreter = 'latex';
            app.C_LmaxLabel_9.Text = '$\Delta C_{l,0 TO}$';
            app.liftreqDeltaCl0TO = uieditfield(app.FlapsTab, 'numeric');
            app.liftreqDeltaCl0TO.ValueDisplayFormat = '%11.6g';
            app.liftreqDeltaCl0TO.Editable = 'off';
            app.cdotfrac1KLabel = uilabel(app.FlapsTab);
            app.cdotfrac1KLabel.Interpreter = 'latex';
            app.cdotfrac1KLabel.Text = '$\cdot \frac{1}{K}=$';
            app.liftPlot = uibutton(app.FlapsTab, 'push');
            app.liftPlot.ButtonPushedFcn = createCallbackFcn(app, @liftPlotButtonPushed, true);
            app.liftPlot.Text = 'Plot';
            app.liftmultideltaflap = uicheckbox(app.FlapsTab);
            app.liftmultideltaflap.ValueChangedFcn = createCallbackFcn(app, @liftmultideltaflapValueChanged, true);
            app.liftmultideltaflap.Text = '';
            app.liftmultideltaflap.Value = true;
            app.delta_fLabel = uilabel(app.FlapsTab);
            app.delta_fLabel.Interpreter = 'latex';
            app.delta_fLabel.Text = '$\delta_f$';
            app.liftmultigradient = uicheckbox(app.FlapsTab);
            app.liftmultigradient.ValueChangedFcn = createCallbackFcn(app, @liftmultigradientValueChanged, true);
            app.liftmultigradient.Text = '';
            app.liftmultigradient.Value = true;
            app.Deltac_lalpha_flapLabel = uilabel(app.FlapsTab);
            app.Deltac_lalpha_flapLabel.Interpreter = 'latex';
            app.Deltac_lalpha_flapLabel.Text = '$\Delta c_{l\alpha_{flap}}$';
            app.Label_11 = uilabel(app.FlapsTab);
            app.Label_11.Interpreter = 'latex';
            app.Label_11.Text = '$\Delta c_{l,\alpha_{flap}}$';
            app.liftgradient = uieditfield(app.FlapsTab, 'numeric');
            app.liftgradient.ValueChangedFcn = createCallbackFcn(app, @liftgradientValueChanged, true);
            app.liftgradient.Tooltip = {'Gradiente de sustentación perfil'};
            app.liftgradient.Value = 5;
            app.RequeridoLabel = uilabel(app.FlapsTab);
            app.RequeridoLabel.Text = 'Requerido';
            app.DisponibleLabel = uilabel(app.FlapsTab);
            app.DisponibleLabel.Text = 'Disponible';
            app.EmpenajeTab = uitab(app.TabGroup6);
            app.EmpenajeTab.Title = 'Empenaje';
            app.EstabilizadorhorizontalLabel = uilabel(app.EmpenajeTab);
            app.EstabilizadorhorizontalLabel.Interpreter = 'latex';
            app.EstabilizadorhorizontalLabel.Text = 'Estabilizador horizontal';
            app.ALabel = uilabel(app.EmpenajeTab);
            app.ALabel.Interpreter = 'latex';
            app.ALabel.Text = '$A$';
            app.LambdaEditField_2Label = uilabel(app.EmpenajeTab);
            app.LambdaEditField_2Label.Interpreter = 'latex';
            app.LambdaEditField_2Label.Text = '$\Lambda$';
            app.lambdaEditField_2Label = uilabel(app.EmpenajeTab);
            app.lambdaEditField_2Label.Interpreter = 'latex';
            app.lambdaEditField_2Label.Text = '$\lambda$';
            app.EstabilizadorverticalLabel = uilabel(app.EmpenajeTab);
            app.EstabilizadorverticalLabel.Interpreter = 'latex';
            app.EstabilizadorverticalLabel.Text = 'Estabilizador vertical';
            app.H_VTEditFieldLabel = uilabel(app.EmpenajeTab);
            app.H_VTEditFieldLabel.Interpreter = 'latex';
            app.H_VTEditFieldLabel.Text = '$H_{VT}$';
            app.empVerHVT = uieditfield(app.EmpenajeTab, 'numeric');
            app.empVerHVT.Limits = [0 Inf];
            app.empVerHVT.ValueChangedFcn = createCallbackFcn(app, @empVerHVTValueChanged, true);
            app.empVerHVT.Tooltip = {'Desplazamiento vertical [ft]'};
            app.empVerHVT.Value = 20;
            app.I_VTEditFieldLabel = uilabel(app.EmpenajeTab);
            app.I_VTEditFieldLabel.Interpreter = 'latex';
            app.I_VTEditFieldLabel.Text = '$I_{VT}$';
            app.empVerIVT = uieditfield(app.EmpenajeTab, 'numeric');
            app.empVerIVT.Limits = [1 Inf];
            app.empVerIVT.ValueChangedFcn = createCallbackFcn(app, @empVerIVTValueChanged, true);
            app.empVerIVT.Tooltip = {'Desplazamiento longitudinal [ft]'};
            app.empVerIVT.Value = 150;
            app.empVerSweep = uieditfield(app.EmpenajeTab, 'numeric');
            app.empVerSweep.Limits = [0 89];
            app.empVerSweep.ValueChangedFcn = createCallbackFcn(app, @empVerSweepValueChanged, true);
            app.empVerSweep.Tooltip = {'Ángulo de barrido'};
            app.empVerSweep.Value = 30;
            app.empVerTaper = uieditfield(app.EmpenajeTab, 'numeric');
            app.empVerTaper.Limits = [0.1 1];
            app.empVerTaper.ValueChangedFcn = createCallbackFcn(app, @empVerTaperValueChanged, true);
            app.empVerTaper.Tooltip = {'Razón de ahusamiento'};
            app.empVerTaper.Value = 0.3;
            app.AEditField_2Label = uilabel(app.EmpenajeTab);
            app.AEditField_2Label.Interpreter = 'latex';
            app.AEditField_2Label.Text = '$A$';
            app.empVerA = uieditfield(app.EmpenajeTab, 'numeric');
            app.empVerA.Limits = [0.1 10];
            app.empVerA.ValueChangedFcn = createCallbackFcn(app, @empVerAValueChanged, true);
            app.empVerA.Tooltip = {'Razón de aspecto'};
            app.empVerA.Value = 5;
            app.H_HTEditFieldLabel = uilabel(app.EmpenajeTab);
            app.H_HTEditFieldLabel.Interpreter = 'latex';
            app.H_HTEditFieldLabel.Text = '$H_{HT}$';
            app.empHorHHT = uieditfield(app.EmpenajeTab, 'numeric');
            app.empHorHHT.Limits = [0 Inf];
            app.empHorHHT.ValueChangedFcn = createCallbackFcn(app, @empHorHHTValueChanged, true);
            app.empHorHHT.Tooltip = {'Desplazamiento vertical [ft]'};
            app.empHorHHT.Value = 10;
            app.I_HTEditFieldLabel = uilabel(app.EmpenajeTab);
            app.I_HTEditFieldLabel.Interpreter = 'latex';
            app.I_HTEditFieldLabel.Text = '$I_{HT}$';
            app.empHorIHT = uieditfield(app.EmpenajeTab, 'numeric');
            app.empHorIHT.Limits = [1 Inf];
            app.empHorIHT.ValueChangedFcn = createCallbackFcn(app, @empHorIHTValueChanged, true);
            app.empHorIHT.Tooltip = {'Desplazamiento longitudinal [ft]'};
            app.empHorIHT.Value = 150;
            app.LambdaEditFieldLabel = uilabel(app.EmpenajeTab);
            app.LambdaEditFieldLabel.Interpreter = 'latex';
            app.LambdaEditFieldLabel.Text = '$\Lambda$';
            app.empHorSweep = uieditfield(app.EmpenajeTab, 'numeric');
            app.empHorSweep.Limits = [0 89];
            app.empHorSweep.ValueChangedFcn = createCallbackFcn(app, @empHorSweepValueChanged, true);
            app.empHorSweep.Tooltip = {'Ángulo de barrido'};
            app.empHorSweep.Value = 30;
            app.lambdaEditFieldLabel = uilabel(app.EmpenajeTab);
            app.lambdaEditFieldLabel.Interpreter = 'latex';
            app.lambdaEditFieldLabel.Text = '$\lambda$';
            app.empHorTaper = uieditfield(app.EmpenajeTab, 'numeric');
            app.empHorTaper.Limits = [0.1 1];
            app.empHorTaper.ValueChangedFcn = createCallbackFcn(app, @empHorTaperValueChanged, true);
            app.empHorTaper.Tooltip = {'Razón de ahusamiento'};
            app.empHorTaper.Value = 0.3;
            app.empHorA = uieditfield(app.EmpenajeTab, 'numeric');
            app.empHorA.Limits = [0.1 10];
            app.empHorA.ValueChangedFcn = createCallbackFcn(app, @empHorAValueChanged, true);
            app.empHorA.Tooltip = {'Razón de aspecto'};
            app.empHorA.Value = 5;
            app.empPlot = uibutton(app.EmpenajeTab, 'push');
            app.empPlot.ButtonPushedFcn = createCallbackFcn(app, @empPlotButtonPushed, true);
            app.empPlot.Text = 'Plot';
            app.CentrodegravedadTab = uitab(app.TabGroup3);
            app.CentrodegravedadTab.Title = 'Centro de gravedad';
            app.TabGroup7 = uitabgroup(app.CentrodegravedadTab);
            app.EjesTab = uitab(app.TabGroup7);
            app.EjesTab.Title = 'Ejes';
            app.x_0EditFieldLabel = uilabel(app.EjesTab);
            app.x_0EditFieldLabel.Interpreter = 'latex';
            app.x_0EditFieldLabel.Text = '$x_0$';
            app.refx0 = uieditfield(app.EjesTab, 'numeric');
            app.refx0.ValueChangedFcn = createCallbackFcn(app, @refx0ValueChanged, true);
            app.x_1Label_3 = uilabel(app.EjesTab);
            app.x_1Label_3.Interpreter = 'latex';
            app.x_1Label_3.Text = '$x_1$';
            app.refx1 = uieditfield(app.EjesTab, 'numeric');
            app.refx1.ValueChangedFcn = createCallbackFcn(app, @refx1ValueChanged, true);
            app.y_0Label = uilabel(app.EjesTab);
            app.y_0Label.Interpreter = 'latex';
            app.y_0Label.Text = '$y_0$';
            app.refy0 = uieditfield(app.EjesTab, 'numeric');
            app.refy0.ValueChangedFcn = createCallbackFcn(app, @refy0ValueChanged, true);
            app.refplot = uibutton(app.EjesTab, 'push');
            app.refplot.ButtonPushedFcn = createCallbackFcn(app, @refplotButtonPushed, true);
            app.refplot.Text = 'Plot';
            app.y_0Label_2 = uilabel(app.EjesTab);
            app.y_0Label_2.Interpreter = 'latex';
            app.y_0Label_2.Text = '$c_{mac,0}$';
            app.refcmac0 = uieditfield(app.EjesTab, 'numeric');
            app.refcmac0.ValueChangedFcn = createCallbackFcn(app, @refcmac0ValueChanged, true);
            app.y_0Label_3 = uilabel(app.EjesTab);
            app.y_0Label_3.Interpreter = 'latex';
            app.y_0Label_3.Text = '$c_{mac,1}$';
            app.refcmac1 = uieditfield(app.EjesTab, 'numeric');
            app.refcmac1.ValueChangedFcn = createCallbackFcn(app, @refcmac1ValueChanged, true);
            app.ComponentesTab = uitab(app.TabGroup7);
            app.ComponentesTab.Title = 'Componentes';
            app.cgtable = uitable(app.ComponentesTab);
            app.cgtable.ColumnName = {'Componente'; 'Peso'; 'x'; 'z'};
            app.cgtable.RowName = {};
            app.cgtable.ColumnEditable = true;
            app.cgtable.CellEditCallback = createCallbackFcn(app, @cgtableCellEdit, true);
            app.cgplus = uibutton(app.ComponentesTab, 'push');
            app.cgplus.ButtonPushedFcn = createCallbackFcn(app, @cgplusButtonPushed, true);
            app.cgplus.Text = '+';
            app.cgminus = uibutton(app.ComponentesTab, 'push');
            app.cgminus.ButtonPushedFcn = createCallbackFcn(app, @cgminusButtonPushed, true);
            app.cgminus.Text = '-';
            app.CategoraDropDownLabel = uilabel(app.ComponentesTab);
            app.CategoraDropDownLabel.Text = 'Categoría';
            app.cgdrop = uidropdown(app.ComponentesTab);
            app.cgdrop.Items = {'Option 1', 'Option 1', 'Option 3', 'Option 4'};
            app.cgdrop.ValueChangedFcn = createCallbackFcn(app, @cgdropValueChanged, true);
            app.NombreEditFieldLabel = uilabel(app.ComponentesTab);
            app.NombreEditFieldLabel.Text = 'Nombre';
            app.cgname = uieditfield(app.ComponentesTab, 'text');
            app.cgname.ValueChangedFcn = createCallbackFcn(app, @cgnameValueChanged, true);
            app.cgcatplus = uibutton(app.ComponentesTab, 'push');
            app.cgcatplus.ButtonPushedFcn = createCallbackFcn(app, @cgcatplusButtonPushed, true);
            app.cgcatplus.Text = '+';
            app.cgcatminus = uibutton(app.ComponentesTab, 'push');
            app.cgcatminus.ButtonPushedFcn = createCallbackFcn(app, @cgcatminusButtonPushed, true);
            app.cgcatminus.Text = '-';
            app.cgplot = uibutton(app.ComponentesTab, 'push');
            app.cgplot.ButtonPushedFcn = createCallbackFcn(app, @cgplotButtonPushed, true);
            app.cgplot.Text = 'Plot';
            app.cgall = uicheckbox(app.ComponentesTab);
            app.cgall.ValueChangedFcn = createCallbackFcn(app, @cgallValueChanged, true);
            app.cgall.Text = 'Todas';
            app.PesototalEditFieldLabel = uilabel(app.ComponentesTab);
            app.PesototalEditFieldLabel.Text = 'Peso total';
            app.cgtotal = uieditfield(app.ComponentesTab, 'numeric');
            app.cgtotal.ValueDisplayFormat = '%.0f';
            app.cgtotal.Editable = 'off';
            app.cgsum = uicheckbox(app.ComponentesTab);
            app.cgsum.ValueChangedFcn = createCallbackFcn(app, @cgsumValueChanged, true);
            app.cgsum.Text = 'Suma';
            app.DiagramaTab = uitab(app.TabGroup7);
            app.DiagramaTab.Title = 'Diagrama';
            app.exttable = uitable(app.DiagramaTab);
            app.exttable.ColumnName = {'Categorias'; 'Peso etapa'};
            app.exttable.RowName = {};
            app.exttable.ColumnEditable = [true false];
            app.exttable.CellEditCallback = createCallbackFcn(app, @exttableCellEdit, true);
            app.extplus = uibutton(app.DiagramaTab, 'push');
            app.extplus.ButtonPushedFcn = createCallbackFcn(app, @extplusButtonPushed, true);
            app.extplus.Text = '+';
            app.extminus = uibutton(app.DiagramaTab, 'push');
            app.extminus.ButtonPushedFcn = createCallbackFcn(app, @extminusButtonPushed, true);
            app.extminus.Text = '-';
            app.explot = uibutton(app.DiagramaTab, 'push');
            app.explot.ButtonPushedFcn = createCallbackFcn(app, @explotButtonPushed, true);
            app.explot.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = roskam

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end